classdef ENME802_Frame_FEA < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                matlab.ui.Figure
        FileMenu                matlab.ui.container.Menu
        NewMenu                 matlab.ui.container.Menu
        OpenMenu                matlab.ui.container.Menu
        SaveMenu                matlab.ui.container.Menu
        SaveAsMenu              matlab.ui.container.Menu
        GridLayout              matlab.ui.container.GridLayout
        TabGroup                matlab.ui.container.TabGroup
        StructureEditorTab      matlab.ui.container.Tab
        GridLayout2             matlab.ui.container.GridLayout
        Panel_2                 matlab.ui.container.Panel
        GridLayout6             matlab.ui.container.GridLayout
        Button_7                matlab.ui.control.Button
        Panel                   matlab.ui.container.Panel
        GridLayout5             matlab.ui.container.GridLayout
        MomentsLabel            matlab.ui.control.Label
        ForcesLabel             matlab.ui.control.Label
        DimentionLabel          matlab.ui.control.Label
        Button_6                matlab.ui.control.Button
        Button_5                matlab.ui.control.Button
        Button_3                matlab.ui.control.Button
        DrawLabel               matlab.ui.control.Label
        SelectLabel             matlab.ui.control.Label
        Button_2                matlab.ui.control.Button
        Button_4                matlab.ui.control.Button
        ElementPropertiesPanel  matlab.ui.container.Panel
        GridLayout4             matlab.ui.container.GridLayout
        StructurePlot           matlab.ui.control.UIAxes
        ResultViewerTab         matlab.ui.container.Tab
    end

    
    properties (Access = private)
        model(1,1) planeFrameModel
        filename(1,1) string
        filepath(1,1) string
        
        %Listener event.listener
    end
    
    methods (Access = private)
        
        function plotStructure(app,src,event)
            cla(app.StructurePlot);
            hold(app.StructurePlot,"on");
            app.model.n_elements
            for i = 1:app.model.n_elements
                n1 = app.model.ncon(i,1);
                n2 = app.model.ncon(i,2);
                x1 = app.model.X(n1);
                x2 = app.model.X(n2);
                y1 = app.model.Y(n1);
                y2 = app.model.Y(n2);
                plot(app.StructurePlot,[x1,x2], [y1,y2], 'LineWidth', 5);
                plot(app.StructurePlot,[x1 x2], [y1 y2], 'ro');
            end
            hold(app.StructurePlot,"off");
        end
        
        
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.model = planeFrameModel;
            app.filename = "Untitled";
            app.filepath = "";

            addlistener( app.model, "StructureChanged", @app.plotStructure);
        end

        % Menu selected function: NewMenu
        function NewMenuSelected(app, event)
            app.model.new;
            app.filename = "Untitled";
            app.filepath = "";
        end

        % Menu selected function: OpenMenu
        function OpenMenuSelected(app, event)
            [file,path] = uigetfile("*.xlsx");
            if ischar(file) && ischar(path)
                try
                    app.model.open(fullfile(path,file));
                    app.filename = file;
                    app.filepath = path;
                catch ME
                    errordlg(ME.message,"File Read Error")
                    app.NewMenuSelected;
                end
            end
        end

        % Menu selected function: SaveMenu
        function SaveMenuSelected(app, event)
            if app.filepath ~= ""
                try
                    app.model.save(fullfile(app.filepath,app.filename));
                catch ME
                    errordlg(ME.message,"File Save Error");
                end
            else
                app.SaveAsMenuSelected;
            end
        end

        % Menu selected function: SaveAsMenu
        function SaveAsMenuSelected(app, event)
            [file,path] = uiputfile("*.xlsx");
            if ischar(file) && ischar(path)
                try
                    app.model.save(fullfile(path,file));
                    app.filename = file;
                    app.filepath = path;
                catch ME
                    errordlg(ME.message,"File Save Error");
                end
            end
        end

        % Button down function: StructurePlot
        function StructurePlotButtonDown(app, event)
            event
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FileMenu = uimenu(app.UIFigure);
            app.FileMenu.Text = 'File';
            app.NewMenu = uimenu(app.FileMenu);
            app.NewMenu.MenuSelectedFcn = createCallbackFcn(app, @NewMenuSelected, true);
            app.NewMenu.Text = 'New';
            app.OpenMenu = uimenu(app.FileMenu);
            app.OpenMenu.MenuSelectedFcn = createCallbackFcn(app, @OpenMenuSelected, true);
            app.OpenMenu.Text = 'Open';
            app.SaveMenu = uimenu(app.FileMenu);
            app.SaveMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveMenuSelected, true);
            app.SaveMenu.Text = 'Save';
            app.SaveAsMenu = uimenu(app.FileMenu);
            app.SaveAsMenu.MenuSelectedFcn = createCallbackFcn(app, @SaveAsMenuSelected, true);
            app.SaveAsMenu.Text = 'Save As';
            app.GridLayout = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout);
            app.StructureEditorTab = uitab(app.TabGroup);
            app.StructureEditorTab.Title = 'Structure Editor';
            app.GridLayout2 = uigridlayout(app.StructureEditorTab);
            app.StructurePlot = uiaxes(app.GridLayout2);
            xlabel(app.StructurePlot, 'X')
            ylabel(app.StructurePlot, 'Y')
            zlabel(app.StructurePlot, 'Z')
            app.StructurePlot.XLimitMethod = 'padded';
            app.StructurePlot.YLimitMethod = 'padded';
            app.StructurePlot.ZLimitMethod = 'padded';
            app.StructurePlot.ButtonDownFcn = createCallbackFcn(app, @StructurePlotButtonDown, true);
            app.ElementPropertiesPanel = uipanel(app.GridLayout2);
            app.ElementPropertiesPanel.Title = 'Element Properties';
            app.GridLayout4 = uigridlayout(app.ElementPropertiesPanel);
            app.Panel = uipanel(app.GridLayout2);
            app.GridLayout5 = uigridlayout(app.Panel);
            app.Button_4 = uibutton(app.GridLayout5, 'push');
            app.Button_4.Icon = fullfile(pathToMLAPP, 'Icons', 'Cursor.png');
            app.Button_4.Text = '';
            app.Button_2 = uibutton(app.GridLayout5, 'push');
            app.Button_2.Icon = fullfile(pathToMLAPP, 'Icons', 'Draw.png');
            app.Button_2.Text = '';
            app.SelectLabel = uilabel(app.GridLayout5);
            app.SelectLabel.Text = 'Select';
            app.DrawLabel = uilabel(app.GridLayout5);
            app.DrawLabel.Text = 'Draw';
            app.Button_3 = uibutton(app.GridLayout5, 'push');
            app.Button_3.Icon = fullfile(pathToMLAPP, 'Icons', 'Dim.png');
            app.Button_3.Text = '';
            app.Button_5 = uibutton(app.GridLayout5, 'push');
            app.Button_5.Icon = fullfile(pathToMLAPP, 'Icons', 'Force.png');
            app.Button_5.Text = '';
            app.Button_6 = uibutton(app.GridLayout5, 'push');
            app.Button_6.Icon = fullfile(pathToMLAPP, 'Icons', 'Moment.png');
            app.Button_6.Text = '';
            app.DimentionLabel = uilabel(app.GridLayout5);
            app.DimentionLabel.Text = 'Dimention';
            app.ForcesLabel = uilabel(app.GridLayout5);
            app.ForcesLabel.Text = 'Forces';
            app.MomentsLabel = uilabel(app.GridLayout5);
            app.MomentsLabel.Text = 'Moments';
            app.Panel_2 = uipanel(app.GridLayout2);
            app.GridLayout6 = uigridlayout(app.Panel_2);
            app.Button_7 = uibutton(app.GridLayout6, 'push');
            app.ResultViewerTab = uitab(app.TabGroup);
            app.ResultViewerTab.Title = 'Result Viewer';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = ENME802_Frame_FEA

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end