classdef FEA_Rigid_2D_Plane_Frames < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        GridLayout2                    matlab.ui.container.GridLayout
        TabGroup                       matlab.ui.container.TabGroup
        ProblemViewerTab               matlab.ui.container.Tab
        GridLayout3                    matlab.ui.container.GridLayout
        Panel                          matlab.ui.container.Panel
        GivenStructureLabel            matlab.ui.control.Label
        ProgramLogTextArea             matlab.ui.control.TextArea
        ProgramLogTextAreaLabel        matlab.ui.control.Label
        SolveButton                    matlab.ui.control.Button
        ResetAllButton                 matlab.ui.control.Button
        Lamp_ReadySolve                matlab.ui.control.Lamp
        ofLabel                        matlab.ui.control.Label
        AllDataReadyLabel              matlab.ui.control.Label
        Rigid2DPlaneFramesLabel        matlab.ui.control.Label
        FiniteElementAnalysisLabel     matlab.ui.control.Label
        UIAxes_Struct                  matlab.ui.control.UIAxes
        ProblemDataPanel               matlab.ui.container.Panel
        Label                          matlab.ui.control.Label
        NodeDropDown_Force             matlab.ui.control.DropDown
        NodeDropDown_2Label_3          matlab.ui.control.Label
        ElementDropDown_DistLoad       matlab.ui.control.DropDown
        NodeDropDown_2Label_2          matlab.ui.control.Label
        MomentsLabel                   matlab.ui.control.Label
        DistLoadsLabel                 matlab.ui.control.Label
        PointForcesLabel               matlab.ui.control.Label
        ElementsLabel                  matlab.ui.control.Label
        NodesLabel                     matlab.ui.control.Label
        DistLoadsDropDown              matlab.ui.control.DropDown
        DistLoadsDropDownLabel         matlab.ui.control.Label
        MomentsDropDown                matlab.ui.control.DropDown
        MomentsDropDownLabel           matlab.ui.control.Label
        FEditFieldLabel_2              matlab.ui.control.Label
        FEditField_DistLoad            matlab.ui.control.NumericEditField
        Aream2EditFieldLabel           matlab.ui.control.Label
        Point2DropDownLabel            matlab.ui.control.Label
        EEditFieldLabel                matlab.ui.control.Label
        Point1DropDownLabel            matlab.ui.control.Label
        ElementDataDropDownLabel       matlab.ui.control.Label
        PointForceDataDropDownLabel    matlab.ui.control.Label
        YPosmEditFieldLabel            matlab.ui.control.Label
        XPosmEditFieldLabel            matlab.ui.control.Label
        FEditFieldLabel                matlab.ui.control.Label
        NodePositionDropDownLabel      matlab.ui.control.Label
        NoMomentsSpinnerLabel          matlab.ui.control.Label
        Im4EditFieldLabel              matlab.ui.control.Label
        NoDistributedForcesSpinnerLabel  matlab.ui.control.Label
        NoPointForcesSpinnerLabel      matlab.ui.control.Label
        NoElementsSpinnerLabel         matlab.ui.control.Label
        NoNodesSpinnerLabel            matlab.ui.control.Label
        ProblemSelectionDropDownLabel  matlab.ui.control.Label
        NodeDropDown_Moment            matlab.ui.control.DropDown
        NodeDropDown_2Label            matlab.ui.control.Label
        DirectionDropDown_Moment       matlab.ui.control.DropDown
        DirectionDropDownLabel         matlab.ui.control.Label
        FEditField_Moment              matlab.ui.control.NumericEditField
        FEditField_3Label              matlab.ui.control.Label
        ForceAxisDropDownLabel         matlab.ui.control.Label
        Lamp_Moments                   matlab.ui.control.Lamp
        MomentSubmitButton             matlab.ui.control.Button
        DropDown_DistLoadUnit          matlab.ui.control.DropDown
        Lamp_DistLoads                 matlab.ui.control.Lamp
        DistLoadSubmitButton           matlab.ui.control.Button
        DropDown_ForceUnit             matlab.ui.control.DropDown
        Lamp_Forces                    matlab.ui.control.Lamp
        ForceSubmitButton              matlab.ui.control.Button
        DropDown_Eunit                 matlab.ui.control.DropDown
        Lamp_Elements                  matlab.ui.control.Lamp
        ElementSubmitButton            matlab.ui.control.Button
        FixedCheckBox                  matlab.ui.control.CheckBox
        Lamp_Nodes                     matlab.ui.control.Lamp
        NodeSubmitButton               matlab.ui.control.Button
        DropDown_MomentUnit            matlab.ui.control.DropDown
        ForceAxisDropDown              matlab.ui.control.DropDown
        Im4EditField                   matlab.ui.control.NumericEditField
        FEditField_Force               matlab.ui.control.NumericEditField
        PointForceDataDropDown         matlab.ui.control.DropDown
        EEditField                     matlab.ui.control.NumericEditField
        Aream2EditField                matlab.ui.control.NumericEditField
        Point2DropDown                 matlab.ui.control.DropDown
        Point1DropDown                 matlab.ui.control.DropDown
        ElementDataDropDown            matlab.ui.control.DropDown
        YPosmEditField                 matlab.ui.control.NumericEditField
        XPosmEditField                 matlab.ui.control.NumericEditField
        NodePositionDropDown           matlab.ui.control.DropDown
        NoMomentsSpinner               matlab.ui.control.Spinner
        NoDistributedForcesSpinner     matlab.ui.control.Spinner
        NoPointForcesSpinner           matlab.ui.control.Spinner
        NoElementsSpinner              matlab.ui.control.Spinner
        NoNodesSpinner                 matlab.ui.control.Spinner
        ProblemSelectionDropDown       matlab.ui.control.DropDown
        SolvedProblemTab               matlab.ui.container.Tab
        DataOutputPanel                matlab.ui.container.Panel
        UITable_Output_2               matlab.ui.control.Table
        UITable_Output                 matlab.ui.control.Table
        OutputScalingPanel             matlab.ui.container.Panel
        RotationScalingSlider          matlab.ui.control.Slider
        RotationScalingLabel           matlab.ui.control.Label
        DeformationScalingSlider       matlab.ui.control.Slider
        DeformationScalingLabel        matlab.ui.control.Label
        DeformedStructurePanel         matlab.ui.container.Panel
        StartOverButton                matlab.ui.control.Button
        UIAxes_Result                  matlab.ui.control.UIAxes
    end


    properties (Access = private)
        %track all data entered
        nodeSubmitCount
        elementSubmitCount
        forceSubmitCount
        UDLSubmitCount
        MomentSubmitCount
        nodeSubmitted
        elementSubmitted
        forceSubmitted
        UDLSubmitted
        MomentSubmitted

        model rigidPlaneFrameModel
        filename string
        filepath string

    end

    methods (Access = private)

        function lockUI(app)%locks the ui page 1
            %LAMPS
            app.Lamp_Moments.Color = [0.5 0.5 0.5]; % Set the color
            app.Lamp_DistLoads.Color = [0.5 0.5 0.5]; % Set the color
            app.Lamp_Forces.Color = [0.5 0.5 0.5]; % Set the color
            app.Lamp_Elements.Color = [0.5 0.5 0.5]; % Set the color
            app.Lamp_Nodes.Color = [0.5 0.5 0.5]; % Set the color
            app.Lamp_ReadySolve.Color = [0.5 0.5 0.5]; % Set the color


            %data entry
            app.NoElementsSpinner.Enable = 'off';
            app.NoNodesSpinner.Enable = 'off';
            app.NoPointForcesSpinner.Enable = 'off';
            app.NoDistributedForcesSpinner.Enable = 'off';
            app.NoMomentsSpinner.Enable='off';

            %node data
            app.NodeSubmitButton.Enable = 'off';
            app.NodePositionDropDown.Enable = 'off';%node selection
            app.EnableNodeEntry('off');

            %element data
            app.ElementSubmitButton.Enable = 'off';
            app.ElementDataDropDown.Enable = 'off';%element selection
            app.EnableElementEntry('off');


            %pointForce data
            app.ForceSubmitButton.Enable = 'off';
            app.PointForceDataDropDown.Enable = 'off';%force selection
            app.EnableForceEntry('off');


            %distributed load data
            app.DistLoadSubmitButton.Enable = 'off';
            app.DistLoadsDropDown.Enable = 'off';%load selection
            app.EnableDistLoadEntry('off');


            %moment data
            app.MomentSubmitButton.Enable = 'off';
            app.MomentsDropDown.Enable = 'off';%moment selection
            app.EnableMomentEntry('off');

        end

        function resetDetails(app, tier) % to run when count changes also
            %turn off bottom section
            %node data
            % app.NodePositionDropDown.Enable = 'off';%node selection
            % app.YPosmEditField.Enable = 'off';
            % app.XPosmEditField.Enable = 'off';
            % app.FixedCheckBox.Enable = 'off';
            % app.NodeSubmitButton.Enable = 'off';

            %element data
            app.ElementDataDropDown.Enable = 'off';%element selection
            app.DropDown_Eunit.Enable = 'off';%kpa
            app.Point1DropDown.Enable = 'off';%first node
            app.Point2DropDown.Enable = 'off';%second node
            app.Im4EditField.Enable = 'off';%moment of inertia
            app.Aream2EditField.Enable = 'off';%element area
            app.EEditField.Enable = 'off';%youngs modulus
            app.ElementSubmitButton.Enable = 'off';

            %pointForce data
            app.PointForceDataDropDown.Enable = 'off';%force selection
            app.DropDown_ForceUnit.Enable = 'off';%force magnitude
            app.ForceAxisDropDown.Enable = 'off';%force direction
            app.NodeDropDown_Force.Enable = 'off'; %force location
            app.FEditField_Force.Enable = 'off';%force value
            app.ForceSubmitButton.Enable = 'off';

            %distributed load data
            app.DistLoadsDropDown.Enable = 'off';%load selection
            app.ElementDropDown_DistLoad.Enable = 'off';%element selection
            app.DropDown_DistLoadUnit.Enable = 'off';%scale
            app.FEditField_DistLoad.Enable = 'off';%distforce value
            app.DistLoadSubmitButton.Enable = 'off';

            %moment data
            app.MomentsDropDown.Enable = 'off';%moment selection
            app.NodeDropDown_Moment.Enable = 'off';%location
            app.DropDown_MomentUnit.Enable = 'off';%magnitude
            app.DirectionDropDown_Moment.Enable = 'off';%direction
            app.FEditField_Moment.Enable = 'off';%moment value
            app.MomentSubmitButton.Enable = 'off';

            %clear based on button pressed
            if tier >= 5
                app.model.NDU = 0;
                app.model.dzero = uint64.empty(0,1);
                app.nodeSubmitCount = 0;
                app.model.X = zeros(2,1,'double');
                app.model.Y = zeros(2,1,'double');
                app.Lamp_Nodes.Color = [0.5 0.5 0.5];
                app.Lamp_Elements.Color = [0.5 0.5 0.5];
                app.UITable_Output.Data = [];
                app.ProgramLogTextArea.Value = [app.ProgramLogTextArea.Value; '---------- ALL DATA RESET ----------'];
                scroll(app.ProgramLogTextArea,"bottom");
                
            end
            if tier >= 4
                app.elementSubmitCount = 0;
                app.model.E = zeros(1,1,'double');
                app.model.ncon = [1 1];
                app.model.I = zeros(1,1,'double');
                app.model.A = zeros(1,1,'double');
                app.Lamp_Nodes.Color = [0.5 0.5 0.5];
            end
            if tier >= 3
                app.forceSubmitCount = 0;
                app.model.Fx = zeros(2,1,'double');
                app.model.Fy = zeros(2,1,'double');
                app.model.n_Forces = 0;
                app.Lamp_Forces.Color = [0.5 0.5 0.5];
            end
            if tier >= 2
                app.UDLSubmitCount = 0;
                app.model.UDL_el = uint64.empty(0,1);
                app.model.UDL = double.empty(0,1);
                app.Lamp_DistLoads.Color = [0.5 0.5 0.5];
                app.model.n_distLoads = 0;
            end
            if tier >= 1
                cla(app.UIAxes_Struct);
                cla(app.UIAxes_Result);
                app.MomentSubmitCount = 0;
                app.model.n_Moments = 0;
                app.model.M = zeros(2,1,'double');
                app.Lamp_Moments.Color = [0.5 0.5 0.5];
                app.DeformationScalingSlider.Enable = "off";
                app.RotationScalingSlider.Enable = "off";
            end
            
            if(app.ProblemSelectionDropDown.Value ~= "Custom")
                app.lockUI();
            end
        end

        function displayStruct(app)
            % Display
            cla(app.UIAxes_Struct);
            hold(app.UIAxes_Struct, 'on');
            %elements
            for i = 1:app.model.n_elements
                n1 = app.model.ncon(i,1);
                n2 = app.model.ncon(i,2);
                x1 = app.model.X(n1);
                x2 = app.model.X(n2);
                y1 = app.model.Y(n1);
                y2 = app.model.Y(n2);
                plot (app.UIAxes_Struct,[x1,x2], [y1,y2], 'LineWidth', 4, 'Color', '#33AAff');
                el_midx = (x1+x2) / 2;
                el_midy = (y1+y2) / 2;
                
                xLimits = app.UIAxes_Struct.XLim;
                yLimits = app.UIAxes_Struct.YLim;

                percentage = 0.025;

                elTextX = el_midx + percentage * (xLimits(2) - xLimits(1));
                elTextY = el_midy + percentage * (yLimits(2) - yLimits(1));

                text(app.UIAxes_Struct, elTextX, elTextY, ['e', num2str(i)],'Color', 'black', 'FontSize', 16,'FontWeight', 'bold', 'HorizontalAlignment', 'center', 'VerticalAlignment', 'middle','Clipping','on');
                plot(app.UIAxes_Struct,[x1,x2], [y1,y2], 'o','Color', '#FF69B4', 'MarkerSize', 8);
            end

            %nodes
            for i = 1:app.model.n_nodes
                x = app.model.X(i);
                y = app.model.Y(i);
                plot (app.UIAxes_Struct,x, y, 'Color', '#999999','Marker','o','LineWidth',5);

                
                Fx = app.model.Fx(i);
                Fy = app.model.Fy(i);
                M = app.model.M(i);

                %%Write Forces
                GraphSize = diff(get(app.UIAxes_Struct, 'XLim'));
                ScaleArrows = GraphSize*.2;
                if Fx ~=0
                    ArrowX = ScaleArrows*sign(Fx);
                    ArrowY = 0;
                    quiver(app.UIAxes_Struct, x-ArrowX, y-ArrowY, ArrowX, ArrowY, 0,'MaxHeadSize', 0.5, 'AutoScale', 'on','Color', 'red', 'LineWidth', 2);
                    text(app.UIAxes_Struct, x-ArrowX, y-ArrowY, [sprintf('%.e', abs(Fx)), ' N'], 'Color', 'red', 'FontSize', 12, 'HorizontalAlignment', 'left', 'VerticalAlignment', 'bottom','Clipping','on');

                end
                if Fy ~=0
                    ArrowX = 0;
                    ArrowY = ScaleArrows*sign(Fy);
                    quiver(app.UIAxes_Struct, x-ArrowX, y-ArrowY, ArrowX, ArrowY, 0,'MaxHeadSize', 0.5, 'AutoScale', 'on','Color', 'red', 'LineWidth', 2);
                    text(app.UIAxes_Struct, x-ArrowX, y-ArrowY, [sprintf('%.e', abs(Fy)), ' N'], 'Color', 'red', 'FontSize', 12, 'HorizontalAlignment', 'left', 'VerticalAlignment', 'bottom','Clipping','on');

                end
                
                %%Writes Nodal Numbers, offset by percent of graph size
                xLimits = app.UIAxes_Struct.XLim;
                yLimits = app.UIAxes_Struct.YLim;

                percentage = 0.02;

                nodeTextX = app.model.X(i) + percentage * (xLimits(2) - xLimits(1));
                nodeTextY = app.model.Y(i) + percentage * (yLimits(2) - yLimits(1));

                text(app.UIAxes_Struct,nodeTextX ,nodeTextY , ['n' num2str(i)],'Color', 'black', 'FontSize', 14, 'FontWeight', 'bold', 'HorizontalAlignment', 'left', 'VerticalAlignment', 'bottom','Clipping','on');


                %%Write Moments
                if M ~= 0

                    % setsup arrow and arc size for the draw.
                    momentRadius = 0.07 *GraphSize ;
                    arrowLen = 0.45 * momentRadius;            % Arrowhead length
                    arrowWidth = 0.375 * momentRadius;         % Arrowhead width

                    % creates vector for angle range and direction for moment
                    if M >= 0
                        theta = linspace(-pi/4, 3*pi/4, 100);
                        arrowAngle = theta(end);
                    else
                        theta = linspace(3*pi/4, -pi/4, 100);
                        arrowAngle = theta(end);
                    end

                    %arc coords
                    arcX = x + momentRadius * cos(theta);
                    arcY = y + momentRadius * sin(theta);

                    % Plot arc
                    plot(app.UIAxes_Struct, arcX, arcY, 'Color', 'Red', 'LineWidth', 2);

                    % Arrowhead computation
                    arcEnd = [arcX(end), arcY(end)];
                    thetaFinal = arrowAngle(end);
                    Tunit = [-sin(thetaFinal), cos(thetaFinal)];

                    % Flip the tangent direction for ccw arrows
                    if M < 0
                        Tunit = -Tunit;
                    end

                    normal = [-Tunit(2), Tunit(1)];

                    arrowTip = arcEnd + arrowLen * Tunit;
                    baseCorner1 = arcEnd + 0.5 * arrowWidth * normal;
                    baseCorner2 = arcEnd - 0.5 * arrowWidth * normal;
       
                    % Draw triangle arrowhead
                    fill(app.UIAxes_Struct, [arrowTip(1) baseCorner1(1) baseCorner2(1)],[arrowTip(2) baseCorner1(2) baseCorner2(2)], 'Red', 'EdgeColor', 'none');

                    text(app.UIAxes_Struct, arcEnd(1), arcEnd(2)-momentRadius, [sprintf('%.0e', abs(M)), ' Nm'], 'Color', '#517891', 'FontSize', 12, 'HorizontalAlignment', 'center', 'VerticalAlignment', 'bottom', 'FontWeight', 'Bold','Clipping','on'); % Add moment label at arc start

                end
            end

            %Draw node fixed markers
            for i = 3:3:app.model.NDU
                node_number = app.model.dzero(i)/3;
                plot(app.UIAxes_Struct, app.model.X(node_number), app.model.Y(node_number), 'rX','MarkerSize', 20, 'LineWidth', 1);
                plot(app.UIAxes_Struct, app.model.X(node_number), app.model.Y(node_number), 'square','MarkerSize', 20, 'LineWidth', 2,'Color','black');
            end

            %%Write UDL
            for i = 1:app.model.n_distLoads
                number_arrows = 8;%this should probs be dynamic
                if ~isnan(app.model.UDL(i)) && app.model.UDL(i) ~= 0        % checks for NAN cause length is 12 and the last 6 are NAN - there will be a smother solution but ima tired   See nonZeroIndices = find(~isnan(app.q_xy) & app.q_xy ~= 0); (before loop, use as i size)

                    n1=app.model.ncon(app.model.UDL_el,1);
                    n2=app.model.ncon(app.model.UDL_el,2);

                    %get node corords from nodes.
                    x1 = app.model.X(n1);
                    y1 = app.model.Y(n1);
                    x2 = app.model.X(n2);
                    y2 = app.model.Y(n2);

                    %element direction
                    x_dir = x2 - x1;
                    y_dir = y2 - y1;

                    % Positions along the element (even spacing)
                    % Arrow scale (fixed arrow length)
                    Scale = 0.2 * diff(get(app.UIAxes_Struct, 'XLim'));

                    % Perpendicular direction (unit vector)
                    perpX = -y_dir;
                    perpY = x_dir;
                    mag = sqrt(perpX^2 + perpY^2);

                    % Normalize
                    perpX = perpX / mag;
                    perpY = perpY / mag;

                    % Scaled perpendicular vector
                    qX = -perpX * Scale;
                    qY = -perpY * Scale;

                    tipX = zeros(1, number_arrows);
                    tipY = zeros(1, number_arrows);

                    % Loop through positions on the element
                    for j = 0:number_arrows-1  %starts at zero to place at start of element.
                        t = j / (number_arrows-1);  % relative position along element
                        x_arrow = x1 + x_dir * t -qX;
                        y_arrow = y1 + y_dir * t -qY;

                        tipX(j + 1) = x_arrow;    
                        tipY(j + 1) = y_arrow;

                        % Draw arrow + force
                        quiver(app.UIAxes_Struct, x_arrow, y_arrow, qX, qY, 0,'Color', 'red', 'LineWidth', 1.5, 'MaxHeadSize', 0.5);
                    end
                    % Plot the line connecting arrow tips

                        plot(app.UIAxes_Struct, tipX, tipY, 'Color', 'red', 'LineWidth', 1.5);
                        lineMidX = (tipX(1) + tipX(end)) / 2;
                        lineMidY = (tipY(1) + tipY(end)) / 2;
                        text(app.UIAxes_Struct, lineMidX, lineMidY, [sprintf('%.e', abs(app.model.UDL(i))), ' N/m'], 'Color', 'black', 'FontSize', 12, 'HorizontalAlignment', 'left', 'VerticalAlignment', 'bottom');
                end
            end


            %make it look pretty
            axis(app.UIAxes_Struct, 'equal');
            ylim(app.UIAxes_Struct, 'padded');
        end

        function displayDeformed(app, sliderd, sliderb)
            cla(app.UIAxes_Result);
            hold(app.UIAxes_Result,"on");
            axis(app.UIAxes_Result, 'equal');
            ylim(app.UIAxes_Result, 'padded');
            % Display Structural Deformation

            % Graphical presentation of Structural Deformation
            % Evaluate new nodal coordinates

            for i = 1:app.model.n_nodes
                u(i) = app.model.nodal_d(3*i-2);
                v(i) = app.model.nodal_d(3*i-1);
                a(i) = app.model.nodal_d(3*i);

                Xnew(i) = app.model.X(i) + sliderd*u(i);
                Ynew(i) = app.model.Y(i) + sliderd*v(i);
            end


            for i = 1:app.model.n_elements
                %assign all data required for plotting
                n1 = app.model.ncon(i,1);
                n2 = app.model.ncon(i,2);
                x1 = app.model.X(n1);
                x2 = app.model.X(n2);
                y1 = app.model.Y(n1);
                y2 = app.model.Y(n2);
                x1_new = Xnew(n1);
                x2_new = Xnew(n2);
                y1_new = Ynew(n1);
                y2_new = Ynew(n2);

                %rotations from bending moments
                theta1 = sliderb*a(n1);
                theta2 = sliderb*a(n2);


                Xline(1,:) = app.model.Xline(i,1,:) * x1_new + app.model.Xline(i,2,:) * x2_new + app.model.Xline(i,3,:) * theta1 + app.model.Xline(i,4,:) * theta2;
                Yline(1,:) = app.model.Yline(i,1,:) * y1_new + app.model.Yline(i,2,:) * y2_new + app.model.Yline(i,3,:) * theta1 + app.model.Yline(i,4,:) * theta2;


                plot(app.UIAxes_Result, Xline, Yline, 'Color','#33AAff', 'LineWidth', 2);

                plot(app.UIAxes_Result, [x1, x2], [y1, y2], 'k--'); % original structure, with dotted line for readability based on matlab codes for finite element analysis by AJM ferreira
                plot(app.UIAxes_Result, [x1_new, x2_new], [y1_new, y2_new], 'ro'); %deformed structure nodes


            end
        end

        function checkReady2Solve(app)
            if(app.ProblemSelectionDropDown.Value ~= "Custom")
                app.Lamp_Nodes.Color = [0 0.8 0];
                app.Lamp_Elements.Color = [0 0.8 0];
                app.Lamp_Forces.Color = [0 0.8 0];
                app.Lamp_DistLoads.Color = [0 0.8 0];
                app.Lamp_Moments.Color = [0 0.8 0];
                app.Lamp_ReadySolve.Color = [0 0.8 0];
                app.SolveButton.Enable = 'on';
            elseif(app.nodeSubmitCount==app.model.n_nodes&&...
                    app.elementSubmitCount==app.model.n_elements&&...
                    app.forceSubmitCount==app.model.n_Forces&&...
                    app.UDLSubmitCount==app.model.n_distLoads&&...
                    app.MomentSubmitCount==app.model.n_Moments)
                app.Lamp_ReadySolve.Color = [0 0.8 0];
                app.SolveButton.Enable = 'on';
            end
        end      

        function resetSubmitCounts(app)
            app.nodeSubmitCount    = 0;
            app.nodeSubmitted = false(1,app.model.n_nodes);
            app.elementSubmitCount = 0;
            app.elementSubmitted = false(1,app.model.n_elements);
            app.forceSubmitCount   = 0;
            app.forceSubmitted = false(1,app.model.n_Forces);
            app.UDLSubmitCount     = 0;
            app.UDLSubmitted = false(1,app.model.n_distLoads);
            app.MomentSubmitCount  = 0;
            app.MomentSubmitted = false(1,app.model.n_Moments);

            %LAMPS
            app.Lamp_Moments.Color = [0.5 0.5 0.5]; % Set the color
            app.Lamp_DistLoads.Color = [0.5 0.5 0.5]; % Set the color
            app.Lamp_Forces.Color = [0.5 0.5 0.5]; % Set the color
            app.Lamp_Elements.Color = [0.5 0.5 0.5]; % Set the color
            app.Lamp_Nodes.Color = [0.5 0.5 0.5]; % Set the color
            app.Lamp_ReadySolve.Color = [0.5 0.5 0.5]; % Set the color
        end

        function unlockDataEntry(app)
            %Counts
            app.NoElementsSpinner.Enable = 'on';
            app.NoNodesSpinner.Enable = 'on';
            app.NoPointForcesSpinner.Enable = 'on';
            app.NoDistributedForcesSpinner.Enable = 'on';
            app.NoMomentsSpinner.Enable='on';

            % Node data
            app.NodeSubmitButton.Enable = 'on';
            app.NodePositionDropDown.Enable = 'on'; % node selection
            app.EnableNodeEntry('on');

            if(app.nodeSubmitCount == app.model.n_nodes)
                % Element data
                app.ElementSubmitButton.Enable = 'on';
                app.ElementDataDropDown.Enable = 'on'; % element selection
                app.EnableElementEntry('on');
            end

            if(app.model.n_Forces~=0)
                % PointForce data
                app.ForceSubmitButton.Enable = 'on';
                app.PointForceDataDropDown.Enable = 'on'; % force selection
                app.EnableForceEntry('on');
            end
            if(app.model.n_distLoads~=0)
                % Distributed load data
                app.DistLoadSubmitButton.Enable = 'on';
                app.DistLoadsDropDown.Enable = 'on'; % load selection
                app.EnableDistLoadEntry('on');
            end
            if(app.model.n_Moments~=0)
                % Moment data
                app.MomentSubmitButton.Enable = 'on';
                app.MomentsDropDown.Enable = 'on'; % moment selection
                app.EnableMomentEntry('on');
            end
        end

        function handleModelChanges(app)
            %element data
            app.ElementDataDropDown.Enable = 'off';%element selection
            app.DropDown_Eunit.Enable = 'off';%kpa
            app.Point1DropDown.Enable = 'off';%first node
            app.Point2DropDown.Enable = 'off';%second node
            app.Im4EditField.Enable = 'off';%moment of inertia
            app.Aream2EditField.Enable = 'off';%element area
            app.EEditField.Enable = 'off';%youngs modulus
            app.ElementSubmitButton.Enable = 'off';

            %pointForce data
            app.PointForceDataDropDown.Enable = 'off';%force selection
            app.DropDown_ForceUnit.Enable = 'off';%force magnitude
            app.ForceAxisDropDown.Enable = 'off';%force direction
            app.NodeDropDown_Force.Enable = 'off'; %force location
            app.FEditField_Force.Enable = 'off';%force value
            app.ForceSubmitButton.Enable = 'off';

            %distributed load data
            app.DistLoadsDropDown.Enable = 'off';%load selection
            app.ElementDropDown_DistLoad.Enable = 'off';%element selection
            app.DropDown_DistLoadUnit.Enable = 'off';%scale
            app.FEditField_DistLoad.Enable = 'off';%distforce value
            app.DistLoadSubmitButton.Enable = 'off';

            %moment data
            app.MomentsDropDown.Enable = 'off';%moment selection
            app.NodeDropDown_Moment.Enable = 'off';%location
            app.DropDown_MomentUnit.Enable = 'off';%magnitude
            app.DirectionDropDown_Moment.Enable = 'off';%direction
            app.FEditField_Moment.Enable = 'off';%moment value
            app.MomentSubmitButton.Enable = 'off';
        
            cla(app.UIAxes_Struct);
            cla(app.UIAxes_Result);
            app.MomentSubmitCount = 0;
            app.Lamp_Moments.Color = [0.5 0.5 0.5];
            app.DeformationScalingSlider.Enable = "off";
            app.RotationScalingSlider.Enable = "off";
            app.UDLSubmitCount = 0;
            app.Lamp_DistLoads.Color = [0.5 0.5 0.5];
            app.forceSubmitCount = 0;
            app.Lamp_Forces.Color = [0.5 0.5 0.5];
            
            app.NodePositionDropDown.Enable = 'on';
            app.NodeSubmitButton.Enable = 'on';
            app.XPosmEditField.Enable = 'on';
            app.YPosmEditField.Enable = 'on';
            app.Lamp_Nodes.Color = [0.5 0.5 0.5];
            app.FixedCheckBox.Enable = 'on';

        end

        function HandleModelEvents(app, src, event)
            if isa(event, 'event.PropertyEvent')
                srcHandle = event.AffectedObject;
                switch src.Name
                    case 'n_nodes'
                        value = srcHandle.n_nodes;
                        app.NoNodesSpinner.Value = double(value);
                        app.NoPointForcesSpinner.Limits = [0 2*double(value)];
                        app.NoMomentsSpinner.Limits = [0 double(value)];

                        app.resetSubmitCounts();

                        list = arrayfun(@(i) sprintf('Node %d', i), 1:value, 'UniformOutput', false);
                        app.NodePositionDropDown.Items = list;
                        app.NodePositionDropDown.ValueIndex = 1;
                        app.Point1DropDown.Items = list;
                        app.Point1DropDown.ValueIndex = 1;
                        app.Point2DropDown.Items = list;
                        app.Point2DropDown.ValueIndex = 1;
                        app.NodeDropDown_Force.Items = list;
                        app.NodeDropDown_Force.ValueIndex = 1;
                        app.NodeDropDown_Moment.Items = list;
                        app.NodeDropDown_Moment.ValueIndex = 1;
                        app.handleModelChanges();
                        

                    case 'n_elements'
                        value = srcHandle.n_elements;
                        app.NoElementsSpinner.Value = double(value);
                        app.NoDistributedForcesSpinner.Limits = [0 double(value)];

                        app.elementSubmitCount = 0;
                        app.elementSubmitted = false(1, value);
                        app.ElementDataDropDown.Items = arrayfun(@(i) sprintf('Element %d', i), 1:value, 'UniformOutput', false);
                        app.ElementDropDown_DistLoad.Items =arrayfun(@(i) sprintf('Element %d', i), 1:value, 'UniformOutput', false);
                        app.handleModelChanges();
                        

                    case 'n_distLoads'
                        value = srcHandle.n_distLoads;
                        app.NoDistributedForcesSpinner.Value = double(value);

                        app.UDLSubmitCount = 0;
                        app.UDLSubmitted = false(1,value);
                        if (value==0)
                            app.Lamp_DistLoads.Color = [0 0.8 0];
                        else
                            app.DistLoadsDropDown.Items = arrayfun(@(i) sprintf('UDL %d', i), 1:value, 'UniformOutput', false);
                        end

                    case 'n_Forces'
                        value = srcHandle.n_Forces;
                        app.NoPointForcesSpinner.Value = double(value);

                        app.forceSubmitCount = 0;
                        app.forceSubmitted = false(1, value);
                        if (value==0)
                            app.Lamp_Forces.Color = [0 0.8 0];
                        else
                            app.PointForceDataDropDown.Items = arrayfun(@(i) sprintf('Force %d', i), 1:value, 'UniformOutput', false);
                        end

                    case 'n_Moments'
                        value = srcHandle.n_Moments;
                        app.NoMomentsSpinner.Value = double(value);

                        app.MomentSubmitCount = 0;
                        app.MomentSubmitted = false(1, value);
                        if (value==0)
                            app.Lamp_Moments.Color = [0 0.8 0];
                        else
                            app.MomentsDropDown.Items = arrayfun(@(i) sprintf('Moment %d', i), 1:value, 'UniformOutput', false);
                        end
                end

            else
                line = "";
                time = string(datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss'));
                switch event.EventName
                    case 'PreprocessStart'
                        line = " - Preprocessing Structure Data...";
                    case 'PreprocessEnd'
                        line = " - Finished Preprocessing, took " + event.time + " sec";
                        app.model.assemble_K();
                    case 'KassembleStart'
                        line = " - Assembling K Matrix...";
                    case 'KassembleEnd'
                        line = " - Finished Assembling K Matrix, took " + event.time + " sec";
                        app.model.solve();
                    case 'SolveStart'
                        line = " - Solving Structure...";
                    case 'SolveEnd'
                        line = " - Finished Solving, took " + event.time + " sec";
                        app.model.postprocess();
                    case 'PostprocessStart'
                        line = " - Postprocessing Data...";
                    case 'PostprocessEnd'
                        line = " - Finished Postprocessing, took " + event.time + " sec";
                        app.displayDeformed(1,1);
                        app.PopulateTable();
                        app.DeformationScalingSlider.Enable = "on";
                        app.RotationScalingSlider.Enable = "on";
                    otherwise
                        line = event.EventName;
                end
                app.ProgramLogTextArea.Value = [app.ProgramLogTextArea.Value; time+line];
                scroll(app.ProgramLogTextArea,"bottom");
            end
        end

        function EnableNodeEntry(app, set)
            if set == "on" || set == "off"
                app.YPosmEditField.Enable = set;
                app.XPosmEditField.Enable = set;
                app.FixedCheckBox.Enable = set;
            end
        end

        function EnableElementEntry(app,set)
            if set == "on" || set == "off"
                app.DropDown_Eunit.Enable = set; % kpa
                app.Point1DropDown.Enable = set; % first node
                app.Point2DropDown.Enable = set; % second node
                app.Im4EditField.Enable = set; % moment of inertia
                app.Aream2EditField.Enable = set; % element area
                app.EEditField.Enable = set; % Young's modulus
            end
        end

        function EnableForceEntry(app, set)
            if set == "on" || set == "off"
                app.DropDown_ForceUnit.Enable = set; % force magnitude
                app.ForceAxisDropDown.Enable = set; % force direction
                app.NodeDropDown_Force.Enable = set; % force location
                app.FEditField_Force.Enable = set; % force value
            end
        end

        function EnableDistLoadEntry(app, set)
            if set == "on" || set == "off"
                app.ElementDropDown_DistLoad.Enable = set; % element selection
                app.DropDown_DistLoadUnit.Enable = set; % scale
                app.FEditField_DistLoad.Enable = set; % distforce value
            end
        end

        function EnableMomentEntry(app, set)
            if set == "on" || set == "off"
                app.NodeDropDown_Moment.Enable = set; % location
                app.DropDown_MomentUnit.Enable = set; % magnitude
                app.DirectionDropDown_Moment.Enable = set; % direction
                app.FEditField_Moment.Enable = set; % moment value
            end
        end
        
        function PopulateTable(app)
            %table 1
                NodeNo(:,1) = 1:app.model.n_nodes;
                U(:,1) = app.model.nodal_d(1:3:app.model.n_nodes*3-2);
                V(:,1) = app.model.nodal_d(2:3:app.model.n_nodes*3-1);
                phi(:,1) = app.model.nodal_d(3:3:app.model.n_nodes*3);
                Fx(:,1) = app.model.nodal_F(1:3:app.model.n_nodes*3-2);
                Fy(:,1) = app.model.nodal_F(2:3:app.model.n_nodes*3-1);
                M(:,1) = app.model.nodal_F(3:3:app.model.n_nodes*3);           
    
                % Example table data (adjust as per your data)
                t = table(NodeNo, U, V, phi, Fx, Fy, M);

                % Specify the number of significant figures
                numSignificantFigures = 2;

                % Format the table columns to the desired number of significant figures
                t.U = arrayfun(@(x) sprintf('%.*g', numSignificantFigures, x), t.U, 'UniformOutput', false);
                t.V = arrayfun(@(x) sprintf('%.*g', numSignificantFigures, x), t.V, 'UniformOutput', false);
                t.phi = arrayfun(@(x) sprintf('%.*g', numSignificantFigures, x), t.phi, 'UniformOutput', false);
                t.Fx = arrayfun(@(x) sprintf('%.*g', numSignificantFigures, x), t.Fx, 'UniformOutput', false);
                t.Fy = arrayfun(@(x) sprintf('%.*g', numSignificantFigures, x), t.Fy, 'UniformOutput', false);
                t.M = arrayfun(@(x) sprintf('%.*g', numSignificantFigures, x), t.M, 'UniformOutput', false);

                % Set the table data with formatted values
                app.UITable_Output.Data = t;
            %table 2
                NodeNo(:,1) = 1:app.model.n_nodes;
                U(:,1) = app.model.nodal_d(1:3:app.model.n_nodes*3-2);
                V(:,1) = app.model.nodal_d(2:3:app.model.n_nodes*3-1);
                phi(:,1) = app.model.nodal_d(3:3:app.model.n_nodes*3);
                Fx(:,1) = app.model.nodal_F(1:3:app.model.n_nodes*3-2);
                Fy(:,1) = app.model.nodal_F(2:3:app.model.n_nodes*3-1);
                M(:,1) = app.model.nodal_F(3:3:app.model.n_nodes*3);           
    
                % Example table data (adjust as per your data)
                t = table(NodeNo, Frx, Fry, Sigma);

                % Specify the number of significant figures
                numSignificantFigures = 2;

                % Format the table columns to the desired number of significant figures
                t.U = arrayfun(@(x) sprintf('%.*g', numSignificantFigures, x), t.U, 'UniformOutput', false);
                t.V = arrayfun(@(x) sprintf('%.*g', numSignificantFigures, x), t.V, 'UniformOutput', false);
                t.phi = arrayfun(@(x) sprintf('%.*g', numSignificantFigures, x), t.phi, 'UniformOutput', false);
                t.Fx = arrayfun(@(x) sprintf('%.*g', numSignificantFigures, x), t.Fx, 'UniformOutput', false);
                t.Fy = arrayfun(@(x) sprintf('%.*g', numSignificantFigures, x), t.Fy, 'UniformOutput', false);
                t.M = arrayfun(@(x) sprintf('%.*g', numSignificantFigures, x), t.M, 'UniformOutput', false);

                % Set the table data with formatted values
                app.UITable_Output_2.Data = t;
        end
    end


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            app.nodeSubmitCount    = 0;
            app.elementSubmitCount = 0;
            app.forceSubmitCount   = 0;
            app.UDLSubmitCount     = 0;
            app.MomentSubmitCount  = 0;

            app.model = rigidPlaneFrameModel;
            addlistener(app.model,'n_nodes','PostSet',@app.HandleModelEvents);
            addlistener(app.model,'n_Forces','PostSet',@app.HandleModelEvents);
            addlistener(app.model,'n_Moments','PostSet',@app.HandleModelEvents);
            addlistener(app.model,'n_elements','PostSet',@app.HandleModelEvents);
            addlistener(app.model,'NDU','PostSet',@app.HandleModelEvents);
            addlistener(app.model,'n_distLoads','PostSet',@app.HandleModelEvents);

            addlistener(app.model,"PreprocessStart", @app.HandleModelEvents);
            addlistener(app.model,"PreprocessEnd", @app.HandleModelEvents);
            addlistener(app.model,"KassembleStart", @app.HandleModelEvents);
            addlistener(app.model,"KassembleEnd", @app.HandleModelEvents);
            addlistener(app.model,"SolveStart", @app.HandleModelEvents);
            addlistener(app.model,"SolveEnd", @app.HandleModelEvents);
            addlistener(app.model,"PostprocessStart", @app.HandleModelEvents);
            addlistener(app.model,"PostprocessEnd", @app.HandleModelEvents);
        end

        % Value changed function: ProblemSelectionDropDown
        function ProblemSelectionDropDownValueChanged(app, event)
            value = app.ProblemSelectionDropDown.Value;
            switch (value)
                case 'Q1'
                    app.model.open('Q1.xlsx');
                    app.lockUI();
                    app.checkReady2Solve();
                case 'Q2'
                    app.model.open('Q2.xlsx');
                    app.lockUI();
                    app.checkReady2Solve();
                case 'Q3'
                    app.model.open('Q3.xlsx');
                    app.lockUI();
                    app.checkReady2Solve();
                case 'Q4'
                    app.model.open('Q4.xlsx');
                    app.lockUI();
                    app.checkReady2Solve();
                case 'Custom'
                    app.model.new();
                    app.resetSubmitCounts();
                    app.unlockDataEntry();
                    cla(app.UIAxes_Struct);
                    drawnow;
            end
            app.displayStruct();
        end

        % Button pushed function: NodeSubmitButton
        function NodeSubmitButtonPushed(app, event)
            %node position data button
            Idx = str2double(regexp(app.NodePositionDropDown.Value, '\d+', 'match', 'once'));
            % Mark this node as submitted (so it won't be counted again)

            app.model.X(Idx) = app.XPosmEditField.Value;
            app.model.Y(Idx) = app.YPosmEditField.Value;
            disp(['Idx: ', num2str(Idx)]);
            if isnan(Idx)
                disp('Error: Invalid Idx value');
            end

            if(app.FixedCheckBox.Value == 1)
                app.model.NDU = app.model.NDU+3;
                n = Idx * 3;
                app.model.dzero(end-2:end) = n-2:n;
            else
                % When unchecked, find and replace corresponding values with NaN
                if length(app.model.dzero) >= 3
                    % Find the indices of the last three nodes (n-2, n-1, n) to remove
                    targetNodes = [Idx * 3 - 2, Idx * 3 - 1, Idx * 3];  % These are the nodes corresponding to n-2, n-1, n

                    % Loop through target nodes and clear cell
                    for i = 1:length(targetNodes)
                        idx = find(app.model.dzero == targetNodes(i), 1);  % Find the index of the current node
                        if ~isempty(idx)
                            app.model.dzero(idx) = [];  % Clear cell
                        end
                    end
                end
            end
            if ~app.nodeSubmitted(Idx)
                app.nodeSubmitted(Idx) = true;
                app.nodeSubmitCount = app.nodeSubmitCount + 1;
                app.EnableNodeEntry('off');
            end

            % Check if all nodes have been submitted
            if app.nodeSubmitCount == app.model.n_nodes
                app.Lamp_Nodes.Color = [0 0.8 0];
                app.NodePositionDropDown.Enable = 'off';
                app.NodeSubmitButton.Enable = 'off';
                app.EnableElementEntry('on');
                app.ElementDataDropDown.Enable = 'on';
                app.ElementSubmitButton.Enable = 'on';
            end
            app.checkReady2Solve();
            app.displayStruct();
        end

        % Button pushed function: ElementSubmitButton
        function ElementSubmitButtonPushed(app, event)
            %element data button
            Idx = str2double(regexp(app.ElementDataDropDown.Value, '\d+', 'match', 'once'));

            E_local = app.EEditField.Value;
            EScale = app.DropDown_Eunit.Value;
            switch(EScale)
                case 'Pa'
                    Emultiplier = 1;
                case 'KPa'
                    Emultiplier = 1000;
                case 'MPa'
                    Emultiplier = 1000000;
                case 'GPa'
                    Emultiplier = 1000000000;
            end
            app.model.E(Idx) = E_local*Emultiplier;
            app.model.ncon(Idx, 1) = str2double(regexp(app.Point1DropDown.Value, '\d+', 'match', 'once'));
            app.model.ncon(Idx, 2) = str2double(regexp(app.Point2DropDown.Value, '\d+', 'match', 'once'));

            app.model.I(Idx) = app.Im4EditField.Value;
            app.model.A(Idx) = app.Aream2EditField;

            % Mark this element as submitted
            if ~app.elementSubmitted(Idx)
                app.elementSubmitted(Idx) = true;
                app.elementSubmitCount = app.elementSubmitCount + 1;
                app.EnableElementEntry('off');
            end

            % Check if all elements have been submitted
            if app.elementSubmitCount == app.model.n_elements
                app.Lamp_Elements.Color = [0 0.8 0];
                app.ElementDataDropDown.Enable = 'off';
                app.ElementSubmitButton.Enable = 'off';
                if app.model.n_Forces > 0
                    app.EnableForceEntry('on');
                    app.PointForceDataDropDown.Enable = 'on';
                    app.ForceSubmitButton.Enable = 'on';
                else
                    app.Lamp_Forces.Color = [0 0.8 0];
                    if app.model.n_distLoads > 0
                        app.EnableDistLoadEntry('on');
                        app.DistLoadsDropDown.Enable = 'on';
                        app.DistLoadSubmitButton.Enable = 'on';
                    else
                        app.Lamp_DistLoads.Color = [0 0.8 0];
                        if app.model.n_Moments > 0
                            app.EnableMomentEntry('on');
                            app.MomentsDropDown.Enable ='on';
                            app.MomentSubmitButton.Enable = 'on';
                        else
                            app.Lamp_Moments.Color = [0 0.8 0];
                        end
                    end
                end
            end
            app.checkReady2Solve();
            app.displayStruct();
        end

        % Button pushed function: ForceSubmitButton
        function ForceSubmitButtonPushed(app, event)
            %force data
            Idx = str2double(regexp(app.PointForceDataDropDown.Value, '\d+', 'match', 'once'));
            forceNode = str2double(regexp(app.NodeDropDown_Force.Value, '\d+', 'match', 'once'));
            Flocal = app.FEditField_Force.Value;
            FScale = app.DropDown_ForceUnit.Value;
            switch(FScale)
                case 'N'
                    FMultiplier = 1;
                case 'kN'
                    FMultiplier = 1000;
            end
            if(app.ForceAxisDropDown.Value == 'X')
                app.model.Fx(forceNode) = Flocal*FMultiplier;
            else
                app.model.Fy(forceNode) = Flocal*FMultiplier;
            end
            % Mark this force as submitted
            if ~app.forceSubmitted(Idx)
                app.forceSubmitted(Idx) = true;
                app.forceSubmitCount = app.forceSubmitCount + 1;
                app.EnableForceEntry('off');
            end
            % Check if all forces have been submitted
            if app.forceSubmitCount == app.model.n_Forces
                app.Lamp_Forces.Color = [0 0.8 0];
                app.PointForceDataDropDown.Enable = 'off';
                app.ForceSubmitButton.Enable = 'off';
                if app.model.n_distLoads > 0
                    app.EnableDistLoadEntry('on');
                    app.DistLoadsDropDown.Enable = 'on';
                    app.DistLoadSubmitButton.Enable = 'on';
                else
                    app.Lamp_DistLoads.Color = [0 0.8 0];
                    if app.model.n_Moments > 0
                        app.EnableMomentEntry('on');
                        app.MomentsDropDown.Enable ='on';
                        app.MomentSubmitButton.Enable = 'on';
                    else
                        app.Lamp_Moments.Color = [0 0.8 0];
                    end
                end
            end
            app.displayStruct();
            app.checkReady2Solve();
        end

        % Button pushed function: DistLoadSubmitButton
        function DistLoadSubmitButtonPushed(app, event)
            %UDL data
            Idx = str2double(regexp(app.DistLoadsDropDown.Value, '\d+', 'match', 'once'));
            app.model.UDL_el(Idx) = str2double(regexp(app.ElementDropDown_DistLoad.Value, '\d+', 'match', 'once'));
            UDLlocal = app.FEditField_DistLoad.Value;
            udlScale = app.DropDown_DistLoadUnit.Value;
            switch(udlScale)
                case 'N/m'
                    FMultiplier = 1;
                case 'kN/m'
                    FMultiplier = 1000;
            end
            app.model.UDL(app.model.UDL_el(Idx)) = UDLlocal*FMultiplier;
            % Mark this force as submitted
            if ~app.UDLSubmitted(Idx)
                app.UDLSubmitted(Idx) = true;
                app.UDLSubmitCount = app.UDLSubmitCount + 1;
                app.EnableDistLoadEntry('off');
            end
            % Check if all forces have been submitted
            if app.UDLSubmitCount == app.model.n_distLoads
                app.Lamp_DistLoads.Color = [0 0.8 0];
                app.DistLoadsDropDown.Enable = 'off';
                app.DistLoadSubmitButton.Enable = 'off';
                if app.model.n_Moments > 0
                    app.EnableMomentEntry('on');
                    app.MomentsDropDown.Enable ='on';
                    app.MomentSubmitButton.Enable = 'on';
                else
                    app.Lamp_Moments.Color = [0 0.8 0];
                end
            end
            app.checkReady2Solve();
            app.displayStruct();
        end

        % Button pushed function: MomentSubmitButton
        function MomentSubmitButtonPushed(app, event)
            % Moment data
            %UDL data
            Idx = str2double(regexp(app.MomentsDropDown.Value, '\d+', 'match', 'once'));
            momIdx = str2double(regexp(app.NodeDropDown_Moment.Value, '\d+', 'match', 'once'));
            momLocal = app.FEditField_Moment.Value;
            momScale = app.DropDown_MomentUnit.Value;
            switch(momScale)
                case 'Nm'
                    FMultiplier = 1;
                case 'kNm'
                    FMultiplier = 1000;
            end
            if(app.DirectionDropDown_Moment.Value == "Clockwise")
                app.model.M(momIdx) = -momLocal*FMultiplier;
            else
                app.model.M(momIdx) = momLocal*FMultiplier;
            end
            % Mark this force as submitted
            if ~app.MomentSubmitted(Idx)
                app.MomentSubmitted(Idx) = true;
                app.MomentSubmitCount = app.MomentSubmitCount + 1;
                app.EnableMomentEntry('off');
            end
            % Check if all forces have been submitted
            if app.MomentSubmitCount == app.model.n_Moments
                app.Lamp_Moments.Color = [0 0.8 0];
                app.MomentsDropDown.Enable = 'off';
                app.MomentSubmitButton.Enable = 'off';
            end
            app.checkReady2Solve();
            app.displayStruct();
        end

        % Button pushed function: ResetAllButton
        function ResetAllButtonPushed(app, event)
            app.model.new();
            app.resetDetails(5);
        end

        % Button pushed function: SolveButton
        function SolveButtonPushed(app, event)
            app.model.preprocess();
            app.DeformationScalingSlider.Value = 1;
            app.RotationScalingSlider.Value = 1;
            app.DeformationScalingSlider.Enable = "on";
            app.RotationScalingSlider.Enable = "on";
        end

        % Button pushed function: StartOverButton
        function StartOverButtonPushed(app, event)
            app.model.new();
            app.resetDetails(5);
        end

        % Value changed function: NoNodesSpinner
        function NoNodesSpinnerValueChanged(app, event)
            app.model.n_nodes = event.Value;
        end

        % Value changed function: NoElementsSpinner
        function NoElementsSpinnerValueChanged(app, event)
            app.model.n_elements = event.Value;
        end

        % Value changed function: NoDistributedForcesSpinner
        function NoDistributedForcesSpinnerValueChanged(app, event)
            app.model.n_distLoads = event.Value;
        end

        % Value changed function: NoPointForcesSpinner
        function NoPointForcesSpinnerValueChanged(app, event)
            app.model.n_Forces = event.Value;
        end

        % Value changed function: NoMomentsSpinner
        function NoMomentsSpinnerValueChanged(app, event)
            app.model.n_Moments = event.Value;
        end

        % Value changed function: NodePositionDropDown
        function NodePositionDropDownValueChanged(app, event)
            Idx = str2double(regexp(app.NodePositionDropDown.Value, '\d+', 'match', 'once'));
            if app.nodeSubmitted(Idx)
                app.EnableNodeEntry('off');
            else
                app.EnableNodeEntry('on');
            end
        end

        % Value changed function: ElementDataDropDown
        function ElementDataDropDownValueChanged(app, event)
            Idx = str2double(regexp(app.ElementDataDropDown.Value, '\d+', 'match', 'once'));
            if app.elementSubmitted(Idx)
                app.EnableElementEntry('off');
            else
                app.EnableElementEntry('on');
            end
        end

        % Value changed function: PointForceDataDropDown
        function PointForceDataDropDownValueChanged(app, event)
            Idx = str2double(regexp(app.PointForceDataDropDown.Value, '\d+', 'match', 'once'));
            if app.forceSubmitted(Idx)
                app.EnableForceEntry('off');
            else
                app.EnableForceEntry('on');
            end
        end

        % Value changed function: DistLoadsDropDown
        function DistLoadsDropDownValueChanged(app, event)
            Idx = str2double(regexp(app.DistLoadsDropDown.Value, '\d+', 'match', 'once'));
            if app.UDLSubmitted(Idx)
                app.EnableDistLoadEntry('off');
            else
                app.EnableDistLoadEntry('on');
            end
        end

        % Value changed function: MomentsDropDown
        function MomentsDropDownValueChanged(app, event)
            Idx = str2double(regexp(app.MomentsDropDown.Value, '\d+', 'match', 'once'));
            if app.MomentSubmitted(Idx)
                app.EnableMomentEntry('off');
            else
                app.EnableMomentEntry('on');
            end
        end

        % Value changed function: DeformationScalingSlider
        function DeformationScalingSliderValueChanged(app, event)
            app.displayDeformed(event.Value,app.RotationScalingSlider.Value);
        end

        % Value changed function: RotationScalingSlider
        function RotationScalingSliderValueChanged(app, event)
            app.displayDeformed(app.DeformationScalingSlider.Value,event.Value); %Redraw 
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.GridLayout2 = uigridlayout(app.UIFigure);
            app.TabGroup = uitabgroup(app.GridLayout2);
            app.ProblemViewerTab = uitab(app.TabGroup);
            app.ProblemViewerTab.Title = 'Problem Viewer';
            app.GridLayout3 = uigridlayout(app.ProblemViewerTab);
            app.ProblemDataPanel = uipanel(app.GridLayout3);
            app.ProblemDataPanel.Title = 'Problem Data';
            app.ProblemSelectionDropDown = uidropdown(app.ProblemDataPanel);
            app.ProblemSelectionDropDown.Items = {'Custom', 'Q1', 'Q2', 'Q3', 'Q4'};
            app.ProblemSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ProblemSelectionDropDownValueChanged, true);
            app.ProblemSelectionDropDown.Value = 'Custom';
            app.NoNodesSpinner = uispinner(app.ProblemDataPanel);
            app.NoNodesSpinner.Limits = [2 Inf];
            app.NoNodesSpinner.ValueChangedFcn = createCallbackFcn(app, @NoNodesSpinnerValueChanged, true);
            app.NoNodesSpinner.Value = 2;
            app.NoElementsSpinner = uispinner(app.ProblemDataPanel);
            app.NoElementsSpinner.Limits = [1 Inf];
            app.NoElementsSpinner.ValueChangedFcn = createCallbackFcn(app, @NoElementsSpinnerValueChanged, true);
            app.NoElementsSpinner.Value = 1;
            app.NoPointForcesSpinner = uispinner(app.ProblemDataPanel);
            app.NoPointForcesSpinner.Limits = [0 Inf];
            app.NoPointForcesSpinner.ValueChangedFcn = createCallbackFcn(app, @NoPointForcesSpinnerValueChanged, true);
            app.NoDistributedForcesSpinner = uispinner(app.ProblemDataPanel);
            app.NoDistributedForcesSpinner.Limits = [0 Inf];
            app.NoDistributedForcesSpinner.ValueChangedFcn = createCallbackFcn(app, @NoDistributedForcesSpinnerValueChanged, true);
            app.NoMomentsSpinner = uispinner(app.ProblemDataPanel);
            app.NoMomentsSpinner.Limits = [0 Inf];
            app.NoMomentsSpinner.ValueChangedFcn = createCallbackFcn(app, @NoMomentsSpinnerValueChanged, true);
            app.NodePositionDropDown = uidropdown(app.ProblemDataPanel);
            app.NodePositionDropDown.Items = {'Node 1'};
            app.NodePositionDropDown.ValueChangedFcn = createCallbackFcn(app, @NodePositionDropDownValueChanged, true);
            app.NodePositionDropDown.Value = 'Node 1';
            app.XPosmEditField = uieditfield(app.ProblemDataPanel, 'numeric');
            app.YPosmEditField = uieditfield(app.ProblemDataPanel, 'numeric');
            app.ElementDataDropDown = uidropdown(app.ProblemDataPanel);
            app.ElementDataDropDown.Items = {'Element 1'};
            app.ElementDataDropDown.ValueChangedFcn = createCallbackFcn(app, @ElementDataDropDownValueChanged, true);
            app.ElementDataDropDown.Enable = 'off';
            app.ElementDataDropDown.Value = 'Element 1';
            app.Point1DropDown = uidropdown(app.ProblemDataPanel);
            app.Point1DropDown.Items = {};
            app.Point1DropDown.Enable = 'off';
            app.Point1DropDown.Placeholder = 'Node 1';
            app.Point1DropDown.Value = {};
            app.Point2DropDown = uidropdown(app.ProblemDataPanel);
            app.Point2DropDown.Items = {};
            app.Point2DropDown.Enable = 'off';
            app.Point2DropDown.Placeholder = 'Node 1';
            app.Point2DropDown.Value = {};
            app.Aream2EditField = uieditfield(app.ProblemDataPanel, 'numeric');
            app.Aream2EditField.Limits = [0 Inf];
            app.Aream2EditField.Enable = 'off';
            app.EEditField = uieditfield(app.ProblemDataPanel, 'numeric');
            app.EEditField.Limits = [0 Inf];
            app.EEditField.Enable = 'off';
            app.PointForceDataDropDown = uidropdown(app.ProblemDataPanel);
            app.PointForceDataDropDown.Items = {'Force 1'};
            app.PointForceDataDropDown.ValueChangedFcn = createCallbackFcn(app, @PointForceDataDropDownValueChanged, true);
            app.PointForceDataDropDown.Enable = 'off';
            app.PointForceDataDropDown.Value = 'Force 1';
            app.FEditField_Force = uieditfield(app.ProblemDataPanel, 'numeric');
            app.FEditField_Force.Enable = 'off';
            app.Im4EditField = uieditfield(app.ProblemDataPanel, 'numeric');
            app.Im4EditField.Limits = [0 Inf];
            app.Im4EditField.Enable = 'off';
            app.ForceAxisDropDown = uidropdown(app.ProblemDataPanel);
            app.ForceAxisDropDown.Items = {'X', 'Y'};
            app.ForceAxisDropDown.Enable = 'off';
            app.ForceAxisDropDown.Value = 'X';
            app.DropDown_MomentUnit = uidropdown(app.ProblemDataPanel);
            app.DropDown_MomentUnit.Items = {'Nm', 'kNm'};
            app.DropDown_MomentUnit.Enable = 'off';
            app.DropDown_MomentUnit.Value = 'Nm';
            app.NodeSubmitButton = uibutton(app.ProblemDataPanel, 'push');
            app.NodeSubmitButton.ButtonPushedFcn = createCallbackFcn(app, @NodeSubmitButtonPushed, true);
            app.NodeSubmitButton.Text = 'Submit';
            app.Lamp_Nodes = uilamp(app.ProblemDataPanel);
            app.FixedCheckBox = uicheckbox(app.ProblemDataPanel);
            app.FixedCheckBox.Text = 'Fixed?';
            app.ElementSubmitButton = uibutton(app.ProblemDataPanel, 'push');
            app.ElementSubmitButton.ButtonPushedFcn = createCallbackFcn(app, @ElementSubmitButtonPushed, true);
            app.ElementSubmitButton.Enable = 'off';
            app.ElementSubmitButton.Text = 'Submit';
            app.Lamp_Elements = uilamp(app.ProblemDataPanel);
            app.DropDown_Eunit = uidropdown(app.ProblemDataPanel);
            app.DropDown_Eunit.Items = {'Pa', 'KPa', 'MPa', 'GPa'};
            app.DropDown_Eunit.Enable = 'off';
            app.DropDown_Eunit.Value = 'Pa';
            app.ForceSubmitButton = uibutton(app.ProblemDataPanel, 'push');
            app.ForceSubmitButton.ButtonPushedFcn = createCallbackFcn(app, @ForceSubmitButtonPushed, true);
            app.ForceSubmitButton.Enable = 'off';
            app.ForceSubmitButton.Text = 'Submit';
            app.Lamp_Forces = uilamp(app.ProblemDataPanel);
            app.DropDown_ForceUnit = uidropdown(app.ProblemDataPanel);
            app.DropDown_ForceUnit.Items = {'N', 'kN'};
            app.DropDown_ForceUnit.Enable = 'off';
            app.DropDown_ForceUnit.Value = 'N';
            app.DistLoadSubmitButton = uibutton(app.ProblemDataPanel, 'push');
            app.DistLoadSubmitButton.ButtonPushedFcn = createCallbackFcn(app, @DistLoadSubmitButtonPushed, true);
            app.DistLoadSubmitButton.Enable = 'off';
            app.DistLoadSubmitButton.Text = 'Submit';
            app.Lamp_DistLoads = uilamp(app.ProblemDataPanel);
            app.DropDown_DistLoadUnit = uidropdown(app.ProblemDataPanel);
            app.DropDown_DistLoadUnit.Items = {'N/m', 'kN/m'};
            app.DropDown_DistLoadUnit.Enable = 'off';
            app.DropDown_DistLoadUnit.Value = 'N/m';
            app.MomentSubmitButton = uibutton(app.ProblemDataPanel, 'push');
            app.MomentSubmitButton.ButtonPushedFcn = createCallbackFcn(app, @MomentSubmitButtonPushed, true);
            app.MomentSubmitButton.Enable = 'off';
            app.MomentSubmitButton.Text = 'Submit';
            app.Lamp_Moments = uilamp(app.ProblemDataPanel);
            app.ForceAxisDropDownLabel = uilabel(app.ProblemDataPanel);
            app.ForceAxisDropDownLabel.Text = 'Force Axis';
            app.FEditField_3Label = uilabel(app.ProblemDataPanel);
            app.FEditField_3Label.Text = 'F';
            app.FEditField_Moment = uieditfield(app.ProblemDataPanel, 'numeric');
            app.FEditField_Moment.Enable = 'off';
            app.DirectionDropDownLabel = uilabel(app.ProblemDataPanel);
            app.DirectionDropDownLabel.Text = 'Direction';
            app.DirectionDropDown_Moment = uidropdown(app.ProblemDataPanel);
            app.DirectionDropDown_Moment.Items = {'Clockwise', 'Anti-Clockwise'};
            app.DirectionDropDown_Moment.Enable = 'off';
            app.DirectionDropDown_Moment.Value = 'Clockwise';
            app.NodeDropDown_2Label = uilabel(app.ProblemDataPanel);
            app.NodeDropDown_2Label.Text = 'Node';
            app.NodeDropDown_Moment = uidropdown(app.ProblemDataPanel);
            app.NodeDropDown_Moment.Items = {};
            app.NodeDropDown_Moment.Enable = 'off';
            app.NodeDropDown_Moment.Placeholder = 'Node 1';
            app.NodeDropDown_Moment.Value = {};
            app.ProblemSelectionDropDownLabel = uilabel(app.ProblemDataPanel);
            app.ProblemSelectionDropDownLabel.Text = 'Problem Selection';
            app.NoNodesSpinnerLabel = uilabel(app.ProblemDataPanel);
            app.NoNodesSpinnerLabel.Text = 'No. Nodes';
            app.NoElementsSpinnerLabel = uilabel(app.ProblemDataPanel);
            app.NoElementsSpinnerLabel.Text = 'No. Elements';
            app.NoPointForcesSpinnerLabel = uilabel(app.ProblemDataPanel);
            app.NoPointForcesSpinnerLabel.Text = 'No. Point Forces';
            app.NoDistributedForcesSpinnerLabel = uilabel(app.ProblemDataPanel);
            app.NoDistributedForcesSpinnerLabel.Text = 'No. UDLs';
            app.Im4EditFieldLabel = uilabel(app.ProblemDataPanel);
            app.Im4EditFieldLabel.Interpreter = 'tex';
            app.Im4EditFieldLabel.Text = 'I (m^4)';
            app.NoMomentsSpinnerLabel = uilabel(app.ProblemDataPanel);
            app.NoMomentsSpinnerLabel.Text = 'No. Moments';
            app.NodePositionDropDownLabel = uilabel(app.ProblemDataPanel);
            app.NodePositionDropDownLabel.Text = 'Node Position';
            app.FEditFieldLabel = uilabel(app.ProblemDataPanel);
            app.FEditFieldLabel.Text = 'F';
            app.XPosmEditFieldLabel = uilabel(app.ProblemDataPanel);
            app.XPosmEditFieldLabel.Text = 'X Pos. (m)';
            app.YPosmEditFieldLabel = uilabel(app.ProblemDataPanel);
            app.YPosmEditFieldLabel.Text = 'Y Pos. (m)';
            app.PointForceDataDropDownLabel = uilabel(app.ProblemDataPanel);
            app.PointForceDataDropDownLabel.Text = 'Point Force Data';
            app.ElementDataDropDownLabel = uilabel(app.ProblemDataPanel);
            app.ElementDataDropDownLabel.Text = 'Element Data';
            app.Point1DropDownLabel = uilabel(app.ProblemDataPanel);
            app.Point1DropDownLabel.Text = 'Point 1';
            app.EEditFieldLabel = uilabel(app.ProblemDataPanel);
            app.EEditFieldLabel.Text = 'E';
            app.Point2DropDownLabel = uilabel(app.ProblemDataPanel);
            app.Point2DropDownLabel.Text = 'Point 2';
            app.Aream2EditFieldLabel = uilabel(app.ProblemDataPanel);
            app.Aream2EditFieldLabel.Interpreter = 'tex';
            app.Aream2EditFieldLabel.Text = 'Area (m^2)';
            app.FEditField_DistLoad = uieditfield(app.ProblemDataPanel, 'numeric');
            app.FEditField_DistLoad.Enable = 'off';
            app.FEditFieldLabel_2 = uilabel(app.ProblemDataPanel);
            app.FEditFieldLabel_2.Text = 'F';
            app.MomentsDropDownLabel = uilabel(app.ProblemDataPanel);
            app.MomentsDropDownLabel.Text = 'Moments';
            app.MomentsDropDown = uidropdown(app.ProblemDataPanel);
            app.MomentsDropDown.Items = {'Moment 1'};
            app.MomentsDropDown.ValueChangedFcn = createCallbackFcn(app, @MomentsDropDownValueChanged, true);
            app.MomentsDropDown.Enable = 'off';
            app.MomentsDropDown.Value = 'Moment 1';
            app.DistLoadsDropDownLabel = uilabel(app.ProblemDataPanel);
            app.DistLoadsDropDownLabel.Text = 'Dist. Loads';
            app.DistLoadsDropDown = uidropdown(app.ProblemDataPanel);
            app.DistLoadsDropDown.Items = {'Load 1'};
            app.DistLoadsDropDown.ValueChangedFcn = createCallbackFcn(app, @DistLoadsDropDownValueChanged, true);
            app.DistLoadsDropDown.Enable = 'off';
            app.DistLoadsDropDown.Tooltip = {'Distributed Loads'};
            app.DistLoadsDropDown.Value = 'Load 1';
            app.NodesLabel = uilabel(app.ProblemDataPanel);
            app.NodesLabel.Text = 'Nodes';
            app.ElementsLabel = uilabel(app.ProblemDataPanel);
            app.ElementsLabel.Text = 'Elements';
            app.PointForcesLabel = uilabel(app.ProblemDataPanel);
            app.PointForcesLabel.Text = 'Point Forces';
            app.DistLoadsLabel = uilabel(app.ProblemDataPanel);
            app.DistLoadsLabel.Text = 'Dist. Loads';
            app.MomentsLabel = uilabel(app.ProblemDataPanel);
            app.MomentsLabel.Text = 'Moments';
            app.NodeDropDown_2Label_2 = uilabel(app.ProblemDataPanel);
            app.NodeDropDown_2Label_2.Text = 'Element';
            app.ElementDropDown_DistLoad = uidropdown(app.ProblemDataPanel);
            app.ElementDropDown_DistLoad.Items = {'Element 1'};
            app.ElementDropDown_DistLoad.Enable = 'off';
            app.ElementDropDown_DistLoad.Value = 'Element 1';
            app.NodeDropDown_2Label_3 = uilabel(app.ProblemDataPanel);
            app.NodeDropDown_2Label_3.Text = 'Node';
            app.NodeDropDown_Force = uidropdown(app.ProblemDataPanel);
            app.NodeDropDown_Force.Items = {};
            app.NodeDropDown_Force.Enable = 'off';
            app.NodeDropDown_Force.Placeholder = 'Node 1';
            app.NodeDropDown_Force.Value = {};
            app.Label = uilabel(app.ProblemDataPanel);
            app.Label.Text = '___________________________________________________________________';
            app.Panel = uipanel(app.GridLayout3);
            app.UIAxes_Struct = uiaxes(app.Panel);
            xlabel(app.UIAxes_Struct, 'X (m)')
            ylabel(app.UIAxes_Struct, 'Y (m)')
            zlabel(app.UIAxes_Struct, 'Z')
            app.FiniteElementAnalysisLabel = uilabel(app.Panel);
            app.FiniteElementAnalysisLabel.Text = 'Finite Element Analysis';
            app.Rigid2DPlaneFramesLabel = uilabel(app.Panel);
            app.Rigid2DPlaneFramesLabel.Text = 'Rigid 2D Plane Frames';
            app.AllDataReadyLabel = uilabel(app.Panel);
            app.AllDataReadyLabel.Text = 'All Data Ready?';
            app.ofLabel = uilabel(app.Panel);
            app.ofLabel.Text = 'of';
            app.Lamp_ReadySolve = uilamp(app.Panel);
            app.ResetAllButton = uibutton(app.Panel, 'push');
            app.ResetAllButton.ButtonPushedFcn = createCallbackFcn(app, @ResetAllButtonPushed, true);
            app.ResetAllButton.Tooltip = {'Be careful!'};
            app.ResetAllButton.Text = 'Reset All';
            app.SolveButton = uibutton(app.Panel, 'push');
            app.SolveButton.ButtonPushedFcn = createCallbackFcn(app, @SolveButtonPushed, true);
            app.SolveButton.Enable = 'off';
            app.SolveButton.Text = 'Solve';
            app.ProgramLogTextAreaLabel = uilabel(app.Panel);
            app.ProgramLogTextAreaLabel.Text = 'Program Log';
            app.ProgramLogTextArea = uitextarea(app.Panel);
            app.ProgramLogTextArea.Editable = 'off';
            app.GivenStructureLabel = uilabel(app.Panel);
            app.GivenStructureLabel.Text = 'Given Structure';
            app.SolvedProblemTab = uitab(app.TabGroup);
            app.SolvedProblemTab.Title = 'Solved Problem';
            app.DeformedStructurePanel = uipanel(app.SolvedProblemTab);
            app.DeformedStructurePanel.Title = 'Deformed Structure';
            app.UIAxes_Result = uiaxes(app.DeformedStructurePanel);
            xlabel(app.UIAxes_Result, 'X (m)')
            ylabel(app.UIAxes_Result, 'Y (m)')
            zlabel(app.UIAxes_Result, 'Z')
            app.StartOverButton = uibutton(app.DeformedStructurePanel, 'push');
            app.StartOverButton.ButtonPushedFcn = createCallbackFcn(app, @StartOverButtonPushed, true);
            app.StartOverButton.Text = 'Start Over';
            app.OutputScalingPanel = uipanel(app.SolvedProblemTab);
            app.OutputScalingPanel.Title = 'Output Scaling';
            app.DeformationScalingLabel = uilabel(app.OutputScalingPanel);
            app.DeformationScalingLabel.Text = {'Deformation'; 'Scaling'};
            app.DeformationScalingSlider = uislider(app.OutputScalingPanel);
            app.DeformationScalingSlider.Limits = [1 1000];
            app.DeformationScalingSlider.MajorTicks = [1 250 500 750 1000];
            app.DeformationScalingSlider.ValueChangedFcn = createCallbackFcn(app, @DeformationScalingSliderValueChanged, true);
            app.DeformationScalingSlider.MinorTicks = [1 50 100 150 200 250 300 350 400 450 500 550 600 650 700 750 800 850 900 950 1000];
            app.DeformationScalingSlider.Enable = 'off';
            app.DeformationScalingSlider.Value = 1;
            app.RotationScalingLabel = uilabel(app.OutputScalingPanel);
            app.RotationScalingLabel.Text = {'Rotation'; 'Scaling'};
            app.RotationScalingSlider = uislider(app.OutputScalingPanel);
            app.RotationScalingSlider.Limits = [1 500];
            app.RotationScalingSlider.MajorTicks = [1 125 250 375 500];
            app.RotationScalingSlider.ValueChangedFcn = createCallbackFcn(app, @RotationScalingSliderValueChanged, true);
            app.RotationScalingSlider.MinorTicks = [1 25 50 75 100 125 150 175 200 225 250 275 300 325 350 375 400 425 450 475 500];
            app.RotationScalingSlider.Enable = 'off';
            app.RotationScalingSlider.Value = 1;
            app.DataOutputPanel = uipanel(app.SolvedProblemTab);
            app.DataOutputPanel.Title = 'Data Output';
            app.UITable_Output = uitable(app.DataOutputPanel);
            app.UITable_Output.ColumnName = {'NodeNo'; 'U'; 'V'; 'phi'; 'Fx'; 'Fy'; 'M'};
            app.UITable_Output.RowName = {};
            app.UITable_Output_2 = uitable(app.DataOutputPanel);
            app.UITable_Output_2.ColumnName = {'NodeNo'; 'U'; 'V'; 'phi'; 'Fx'; 'Fy'; 'M'};
            app.UITable_Output_2.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = FEA_Rigid_2D_Plane_Frames

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end