classdef cae_gui_scratch < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                       matlab.ui.Figure
        TabGroup                       matlab.ui.container.TabGroup
        ProblemViewerTab               matlab.ui.container.Tab
        ResetAllButton                 matlab.ui.control.Button
        DataPanel                      matlab.ui.container.Panel
        NodeDropDown_4                 matlab.ui.control.DropDown
        NodeDropDown_2Label_3          matlab.ui.control.Label
        NodeDropDown_3                 matlab.ui.control.DropDown
        NodeDropDown_2Label_2          matlab.ui.control.Label
        ForceAxisDropDownLabel_2       matlab.ui.control.Label
        ForceAxisDropDown_2            matlab.ui.control.DropDown
        MomentsLabel                   matlab.ui.control.Label
        DistLoadsLabel                 matlab.ui.control.Label
        PointForcesLabel               matlab.ui.control.Label
        ElementsLabel                  matlab.ui.control.Label
        NodesLabel                     matlab.ui.control.Label
        DistLoadsDropDown              matlab.ui.control.DropDown
        DistLoadsDropDownLabel         matlab.ui.control.Label
        MomentsDropDown                matlab.ui.control.DropDown
        MomentsDropDownLabel           matlab.ui.control.Label
        FEditFieldLabel_2              matlab.ui.control.Label
        FEditField_3                   matlab.ui.control.NumericEditField
        Aream2EditFieldLabel           matlab.ui.control.Label
        Point2DropDownLabel            matlab.ui.control.Label
        EEditFieldLabel                matlab.ui.control.Label
        Point1DropDownLabel            matlab.ui.control.Label
        ElementDataDropDownLabel       matlab.ui.control.Label
        PointForceDataDropDownLabel    matlab.ui.control.Label
        YPosmEditFieldLabel            matlab.ui.control.Label
        XPosmEditFieldLabel            matlab.ui.control.Label
        FEditFieldLabel                matlab.ui.control.Label
        NodePositionDropDownLabel      matlab.ui.control.Label
        NoMomentsSpinnerLabel          matlab.ui.control.Label
        Im4EditFieldLabel              matlab.ui.control.Label
        NoDistributedForcesSpinnerLabel  matlab.ui.control.Label
        NoPointForcesSpinnerLabel      matlab.ui.control.Label
        NoElementsSpinnerLabel         matlab.ui.control.Label
        NoNodesSpinnerLabel            matlab.ui.control.Label
        ProblemSelectionDropDownLabel  matlab.ui.control.Label
        NodeDropDown                   matlab.ui.control.DropDown
        NodeDropDown_2Label            matlab.ui.control.Label
        DirectionDropDown              matlab.ui.control.DropDown
        DirectionDropDownLabel         matlab.ui.control.Label
        FEditField_2                   matlab.ui.control.NumericEditField
        FEditField_3Label              matlab.ui.control.Label
        ForceAxisDropDownLabel         matlab.ui.control.Label
        Lamp_21                        matlab.ui.control.Lamp
        SubmitButton_5                 matlab.ui.control.Button
        DropDown_4                     matlab.ui.control.DropDown
        Lamp_20                        matlab.ui.control.Lamp
        SubmitButton_4                 matlab.ui.control.Button
        DropDown_3                     matlab.ui.control.DropDown
        Lamp_19                        matlab.ui.control.Lamp
        SubmitButton_3                 matlab.ui.control.Button
        DropDown_2                     matlab.ui.control.DropDown
        Lamp_18                        matlab.ui.control.Lamp
        SubmitButton_2                 matlab.ui.control.Button
        FixedCheckBox                  matlab.ui.control.CheckBox
        Lamp_17                        matlab.ui.control.Lamp
        SubmitButton                   matlab.ui.control.Button
        DataLoadedLabel                matlab.ui.control.Label
        Lamp_16                        matlab.ui.control.Lamp
        SubmitResetButton_5            matlab.ui.control.Button
        Lamp_15                        matlab.ui.control.Lamp
        DropDown                       matlab.ui.control.DropDown
        SubmitResetButton_4            matlab.ui.control.Button
        Lamp_14                        matlab.ui.control.Lamp
        SubmitResetButton_3            matlab.ui.control.Button
        Lamp_13                        matlab.ui.control.Lamp
        SubmitResetButton_2            matlab.ui.control.Button
        Lamp_12                        matlab.ui.control.Lamp
        ForceAxisDropDown              matlab.ui.control.DropDown
        Im4EditField                   matlab.ui.control.NumericEditField
        FEditField                     matlab.ui.control.NumericEditField
        PointForceDataDropDown         matlab.ui.control.DropDown
        EEditField                     matlab.ui.control.NumericEditField
        Aream2EditField                matlab.ui.control.NumericEditField
        Point2DropDown                 matlab.ui.control.DropDown
        Point1DropDown                 matlab.ui.control.DropDown
        ElementDataDropDown            matlab.ui.control.DropDown
        YPosmEditField                 matlab.ui.control.NumericEditField
        XPosmEditField                 matlab.ui.control.NumericEditField
        NodePositionDropDown           matlab.ui.control.DropDown
        NoMomentsSpinner               matlab.ui.control.Spinner
        NoDistributedForcesSpinner     matlab.ui.control.Spinner
        NoPointForcesSpinner           matlab.ui.control.Spinner
        NoElementsSpinner              matlab.ui.control.Spinner
        NoNodesSpinner                 matlab.ui.control.Spinner
        ProblemSelectionDropDown       matlab.ui.control.DropDown
        SubmitResetButton              matlab.ui.control.Button
        Lamp_11                        matlab.ui.control.Lamp
        AlldatareadyLabel              matlab.ui.control.Label
        GivenStructureLabel            matlab.ui.control.Label
        SolveButton                    matlab.ui.control.Button
        UIAxes_4                       matlab.ui.control.UIAxes
        SolvedProblemTab               matlab.ui.container.Tab
        DataOutputPanel                matlab.ui.container.Panel
        UITable                        matlab.ui.control.Table
        UserInputPanel                 matlab.ui.container.Panel
        ApplyButton_2                  matlab.ui.control.Button
        ApplyButton                    matlab.ui.control.Button
        RotationScalingSlider          matlab.ui.control.Slider
        RotationScalingLabel           matlab.ui.control.Label
        DeformationScalingSlider       matlab.ui.control.Slider
        DeformationScalingLabel        matlab.ui.control.Label
        DeformedStructurePanel         matlab.ui.container.Panel
        StartOverButton                matlab.ui.control.Button
        UIAxes_3                       matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        %original variables
        n_element %number of elements (number)
        n_nodes % number of nodes (number)
        E %youngs modulus (vector)
        A %area of elements (vector)
        ncon1
        ncon2 %node connections (matrix)
        X %node x positions (vector)
        Y %node y positions (vector)
        NDU %number of nodes with zero displacement
        dzero %nodes with zero displacement (vector)
        F %forces (vector)
        KE %element stiffness matrix
        K %global stiffness matrix
        KT %temporary stiffness matrix
        d %displacements (vector)
        FR %resulting force (vector)
        Sigma %stress (vector)
        data %data

        %additional information for rigid plane frames
        Area %element area(vector)
        M %moments by node (vector)
        I %element moment of inertia(vector)
        q_xy %distloads (matrix)

        %multi element vector/matirx deconstruction
        Fx %x forces by node
        Fy %y forces by node
        qx
        qy

        %number of moments, forces, distributed loads for ui
        noForces
        noUDLs
        noMoments

        %track all data entered
        nodeSubmitCount
        elementSubmitCount
        forceSubmitCount
        UDLSubmitCount
        MomentSubmitCount
        nodeSubmitted
        elementSubmitted
        forceSubmitted   
        UDLSubmitted    
        MomentSubmitted
        %switch case
        tier

    end

    methods (Access = private)

        function lockUI(app)%locks the ui page 1
            %LAMPS
            for i = 11:21
                lampName = sprintf('Lamp_%d', i); % Generate lamp variable name dynamically
                app.(lampName).Color = [0.5 0.5 0.5]; % Set the color
            end

            %data entry
            app.NoElementsSpinner.Enable = 'off';
            app.SubmitResetButton_2.Enable = 'off';

            app.NoNodesSpinner.Enable = 'off';
            app.SubmitResetButton.Enable = 'off';

            app.NoPointForcesSpinner.Enable = 'off';
            app.SubmitResetButton_3.Enable = 'off';

            app.NoDistributedForcesSpinner.Enable = 'off';
            app.SubmitResetButton_4.Enable = 'off';

            app.NoMomentsSpinner.Enable='off';
            app.SubmitResetButton_5.Enable = 'off';

            %node data
            app.SubmitButton.Enable = 'off';
            app.NodePositionDropDown.Enable = 'off';%node selection
            app.YPosmEditField.Enable = 'off';
            app.XPosmEditField.Enable = 'off';
            app.FixedCheckBox.Enable = 'off';

            %element data
            app.ElementDataDropDown.Enable = 'off';%element selection
            app.DropDown_2.Enable = 'off';%kpa
            app.Point1DropDown.Enable = 'off';%first node
            app.Point2DropDown.Enable = 'off';%second node
            app.Im4EditField.Enable = 'off';%moment of inertia
            app.Aream2EditField.Enable = 'off';%element area
            app.EEditField.Enable = 'off';%youngs modulus

            %pointForce data
            app.PointForceDataDropDown.Enable = 'off';%force selection
            app.DropDown_3.Enable = 'off';%force magnitude
            app.ForceAxisDropDown.Enable = 'off';%force direction
            app.NodeDropDown_4.Enable = 'off'; %force location
            app.FEditField.Enable = 'off';%force value

            %distributed load data
            app.DistLoadsDropDown.Enable = 'off';%load selection
            app.NodeDropDown_3.Enable = 'off';%element selection
            app.ForceAxisDropDown_2.Enable = 'off';%force direction
            app.DropDown_4.Enable = 'off';%scale
            app.FEditField_3.Enable = 'off';%distforce value

            %moment data
            app.MomentsDropDown.Enable = 'off';%moment selection
            app.NodeDropDown.Enable = 'off';%location
            app.DropDown.Enable = 'off';%magnitude
            app.DirectionDropDown.Enable = 'off';%direction
            app.FEditField_2.Enable = 'off';%moment value
        end

        function resetdetails(app, tier)

            % Clear based on tier level
            switch (tier)
                case 1  % Clear everything
                    
                case 2  % Clear everything except element data

                case 3  % Clear everything except node and element data


                case 4  % Clear everything except node, element, and point force data

                case 5  % Clear everything except node, element, point force, and distributed load data

                case 6  % Clear only moment data

            end

            %node data
            app.NodePositionDropDown.Enable = 'off';%node selection
            app.YPosmEditField.Enable = 'off';
            app.XPosmEditField.Enable = 'off';
            app.FixedCheckBox.Enable = 'off';
            app.SubmitButton.Enable = 'off';

            %element data
            app.ElementDataDropDown.Enable = 'off';%element selection
            app.DropDown_2.Enable = 'off';%kpa
            app.Point1DropDown.Enable = 'off';%first node
            app.Point2DropDown.Enable = 'off';%second node
            app.Im4EditField.Enable = 'off';%moment of inertia
            app.Aream2EditField.Enable = 'off';%element area
            app.EEditField.Enable = 'off';%youngs modulus

            %pointForce data
            app.PointForceDataDropDown.Enable = 'off';%force selection
            app.DropDown_3.Enable = 'off';%force magnitude
            app.ForceAxisDropDown.Enable = 'off';%force direction
            app.NodeDropDown_4.Enable = 'off'; %force location
            app.FEditField.Enable = 'off';%force value

            %distributed load data
            app.DistLoadsDropDown.Enable = 'off';%load selection
            app.NodeDropDown_3.Enable = 'off';%element selection
            app.ForceAxisDropDown_2.Enable = 'off';%force direction
            app.DropDown_4.Enable = 'off';%scale
            app.FEditField_3.Enable = 'off';%distforce value

            %moment data
            app.MomentsDropDown.Enable = 'off';%moment selection
            app.NodeDropDown.Enable = 'off';%location
            app.DropDown.Enable = 'off';%magnitude
            app.DirectionDropDown.Enable = 'off';%direction
            app.FEditField_2.Enable = 'off';%moment value
        end

        function getdata(app, data)%for use in spreadsheet functions

            %reset
            app.n_element = [];
            app.n_nodes = [];
            app.E = [];
            app.Area = [];
            app.ncon = [];
            app.X = [];
            app.Y = [];
            app.NDU = [];
            app.dzero = [];
            app.F = [];
            app.q_xy = [];
            app.M = [];
            app.I = [];
            app.KE = [];
            app.K = [];

            %set data from spreadsheet
            app.n_element = data(1,1);
            app.n_nodes = data(1,2);

            E_ym = data(1,7);
            E_ym_multiplier = data(1,12);
            app.E = E_ym * 10^E_ym_multiplier;

            app.Area = data(:,8);

            app.ncon = [data(:,3), data(:,4)];
            app.X = data(:,5);
            app.Y = data(:,6);

            app.NDU = data(1,10);
            app.dzero = data(1:app.NDU,11);

            app.F = data(:,9);
            app.q_xy = data(:,14);

            app.M = data(:,12);
            app.I = data(:,13);

            app.KE = zeros(6);
            app.K = zeros(3 * app.n_nodes);
            app.displayStruct();
        end

        function displayStruct(app)%should run every time submit is pressed for node and element data
            % Display
            cla(app.UIAxes_4);
            app.KE = zeros(6);
            app.K = zeros(3*app.n_nodes);
            for i = 1:app.n_element
                n1 = app.ncon(i,1);
                n2 = app.ncon(i,2);
                x1 = app.X(n1);
                x2 = app.X(n2);
                y1 = app.Y(n1);
                y2 = app.Y(n2);
                plot ([app.UIAxes_4],[x1,x2], [y1,y2], 'LineWidth', 4);
                hold(app.UIAxes_4, 'on');
                plot ([app.UIAxes_4],[x1,x2], [y1,y2], 'ro');
                hold(app.UIAxes_4, 'on');
            end
            %make it look pretty
            axis equal;
            hold off;

        end

        function preProcess(app)%should only run when all green lights are on
            for i = 1:app.n_element

                % Evaluate elental stiffness matrices

                n1 = app.ncon(i,1);
                n2 = app.ncon(i,2);
                x1 = app.X(n1);
                x2 = app.X(n2);
                y1 = app.Y(n1);
                y2 = app.Y(n2);

                %Initiation and Pre-processing
                alpha = atan2((y2-y1),(x2-x1));
                c = cos(alpha);
                s = sin(alpha);
                L = sqrt((x2-x1)^2+(y2-y1)^2);

                const = app.E(i)/L;

                app.KE =    [(app.Area(i)*c^2+((12*app.I(i)*s^2)/L^2)), (app.Area(i)-(12*app.I(i)/L^2))*c*s, -(6*app.I(i)*s/L), -(app.Area(i)*c^2+((12*app.I(i)*s^2)/L^2)), -(app.Area(i)-(12*app.I(i)/L^2))*c*s, -(6*app.I(i)*s/L);
                    (app.Area(i)-(12*app.I(i)/L^2))*c*s, (app.Area(i)*s^2+((12*app.I(i)*c^2)/L^2)), (6*app.I(i)*c/L), -(app.Area(i)-(12*app.I(i)/L^2))*c*s, -(app.Area(i)*s^2+((12*app.I(i)*c^2)/L^2)), (6*app.I(i)*c/L);
                    -(6*app.I(i)*s/L), (6*app.I(i)*c/L), 4*app.I(i), 6*app.I(i)*s/L, -(6*app.I(i)*c/L), 2*app.I(i);

                    -(app.Area(i)*c^2+((12*app.I(i)*s^2)/L^2)), -(app.Area(i)-(12*app.I(i)/L^2))*c*s, (6*app.I(i)*s/L), (app.Area(i)*c^2+((12*app.I(i)*s^2)/L^2)), (app.Area(i)-(12*app.I(i)/L^2))*c*s, (6*app.I(i)*s/L);
                    -(app.Area(i)-(12*app.I(i)/L^2))*c*s, -(app.Area(i)*s^2+((12*app.I(i)*c^2)/L^2)), -(6*app.I(i)*c/L), (app.Area(i)-(12*app.I(i)/L^2))*c*s, (app.Area(i)*s^2+((12*app.I(i)*c^2)/L^2)), -(6*app.I(i)*c/L);
                    -(6*app.I(i)*s/L), (6*app.I(i)*c/L), 2*app.I(i), 6*app.I(i)*s/L, -(6*app.I(i)*c/L), 4*app.I(i)];

                app.KE = const*app.KE;

                % Assembly
                % Assemble into overall stiffness matrix

                j = n1;
                k = n2;

                III(1) = (3*j) - 2;
                III(2) = (3*j) - 1;
                III(3) = (3*j);
                III(4) = (3*k) - 2;
                III(5) = (3*k) - 1;
                III(6) = (3*k);


                for VI = 1:6
                    MI = III(VI);
                    for JX = 1:6
                        MJ = III(JX);
                        app.K(MI,MJ) = app.K(MI,MJ) + app.KE(VI,JX);
                    end
                end
            end
        end
        
        function checkReady2Solve(app)
            if(app.nodeSubmitCount==app.n_nodes&&...
                app.elementSubmitCount==app.n_element&&...
                app.forceSubmitCount==app.noForces&&...
                app.UDLSubmitCount==app.noUDLs&&...
                app.MomentSubmitCount==app.noMoments)
                app.Lamp_11.Color = [0 1 0];
                app.SolveButton.Enable = 'on';
            end
        end
        
        function reset(app)
            app.dzero = [];
            app.NDU = 0;
            % Initialise per-item submission tracking (logical arrays)
            app.nodeSubmitted    = false(1, app.n_nodes);
            app.elementSubmitted = false(1, app.n_element);
            app.forceSubmitted   = false(1, app.noForces);   
            app.UDLSubmitted     = false(1, app.noUDLs);    
            app.MomentSubmitted  = false(1, app.noMoments);  

            % Initialise counters
            app.nodeSubmitCount    = 0;
            app.elementSubmitCount = 0;
            app.forceSubmitCount   = 0;
            app.UDLSubmitCount     = 0;
            app.MomentSubmitCount  = 0;

        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.dzero = [];
            app.NDU = 0;
            app.nodeSubmitCount    = 0;
            app.elementSubmitCount = 0;
            app.forceSubmitCount   = 0;
            app.UDLSubmitCount     = 0;
            app.MomentSubmitCount  = 0;
        end

        % Value changed function: ProblemSelectionDropDown
        function ProblemSelectionDropDownValueChanged(app, event)
            value = app.ProblemSelectionDropDown.Value;      
            switch (value)
                case 'Q1'
                    app.data = readmatrix('data_structure_rigid_plane_frames_q1.xlsx');
                    app.getdata(app.data);
                    app.lockUI();
                case 'Q2'
                    app.data = readmatrix('data_structure_rigid_plane_frames_q2.xlsx');
                    app.getdata(app.data);
                    app.lockUI();
                case 'Q3'
                    app.data = readmatrix('data_structure_rigid_plane_frames_q3.xlsx');
                    app.getdata(app.data);
                    app.lockUI();
                case 'Q4'
                    app.data = readmatrix('data_structure_rigid_plane_frames_q4.xlsx');
                    app.getdata(app.data);
                    app.lockUI();
                case 'Custom'
                    app.reset();
                    app.NoElementsSpinner.Enable = 'on';
                    app.SubmitResetButton_2.Enable = 'on';
                    cla(app.UIAxes_4);
                    drawnow;
            end
        end

        % Button pushed function: SubmitResetButton
        function SubmitResetButtonPushed(app, event)
            %NODE NUMBERS
            %app.resetdetails(2);
            value = app.NoNodesSpinner.Value;
            app.noForces = value;
            app.Fx = zeros(1,value);
            app.Fy = zeros(1,value);
            app.X = zeros(1,value);
            app.Y = zeros(1,value);
            app.F = zeros(3*value, 1);
            app.M = zeros(1,value);
            app.n_nodes = value;
            app.nodeSubmitted = false(1, app.n_nodes);
                       
            app.NoPointForcesSpinner.Enable = 'on';
            app.SubmitResetButton_3.Enable = 'on';

            app.Lamp_13.Color = [0 1 0];
            
            app.NoDistributedForcesSpinner.Enable = 'off';
            app.SubmitResetButton_4.Enable = 'off';

            app.NoMomentsSpinner.Enable='off';
            app.SubmitResetButton_5.Enable = 'off';
            
            list = arrayfun(@(i) sprintf('Node %d', i), 1:value, 'UniformOutput', false);
            app.NodePositionDropDown.Items = list;
            app.Point1DropDown.Items = list;
            app.Point2DropDown.Items = list;
            app.NodeDropDown_4.Items = list;
            app.NodeDropDown.Items = list;
        end

        % Button pushed function: SubmitResetButton_2
        function SubmitResetButton_2Pushed(app, event)
            %ELEMENT NUMBERS
            app.n_element = app.NoElementsSpinner.Value;
            value = app.n_element;
            app.qx = zeros(1,value);
            app.qy = zeros(1,value);
            app.ncon1 = zeros(1,value);
            app.ncon2 = zeros(1,value);
            app.X = zeros(1,value);
            app.Y=zeros(1,value);
            %app.resetdetails(1);
            app.elementSubmitted = false(1, app.n_element);
            
            %limit no. nodes
            maxNodes = value+1;%avoids floating nodes
            minNodes = 2;%should be an algorithm for this, no intersections no overlaps

            app.NoNodesSpinner.Limits = [minNodes, maxNodes];
            app.NoNodesSpinner.Value = minNodes;%reset num modes
            app.NoNodesSpinner.Enable = 'on';
            app.SubmitResetButton.Enable = 'on';
            app.Lamp_12.Color = [0 1 0];

            app.NoPointForcesSpinner.Enable = 'off';
            app.SubmitResetButton_3.Enable = 'off';

            app.NoDistributedForcesSpinner.Enable = 'off';
            app.SubmitResetButton_4.Enable = 'off';

            app.NoMomentsSpinner.Enable='off';
            app.SubmitResetButton_5.Enable = 'off';

            app.ElementDataDropDown.Items = arrayfun(@(i) sprintf('Element %d', i), 1:value, 'UniformOutput', false);
            app.NodeDropDown_3.Items =arrayfun(@(i) sprintf('Element %d', i), 1:value, 'UniformOutput', false);  
            disp('executed')
        end

        % Button pushed function: SubmitResetButton_3
        function SubmitResetButton_3Pushed(app, event)
            %No. OF FORCES
            %app.resetdetails(3);
            value = app.NoPointForcesSpinner.Value;
            app.noForces = value;            
            app.NoDistributedForcesSpinner.Enable = 'on';
            app.SubmitResetButton_4.Enable = 'on';
            app.Lamp_14.Color = [0 1 0];
            app.forceSubmitted   = false(1, app.noForces);   
                        
            if (value==0)
                app.Lamp_19.Color = [0 1 0];
            else
                app.PointForceDataDropDown.Items = arrayfun(@(i) sprintf('Force %d', i), 1:value, 'UniformOutput', false);
            end
        end

        % Button pushed function: SubmitResetButton_4
        function SubmitResetButton_4Pushed(app, event)
            %No. OF UDLs
            %app.resetdetails(4);
            value = app.NoDistributedForcesSpinner.Value;
            app.noUDLs = value;
            app.NoMomentsSpinner.Enable='on';
            app.SubmitResetButton_5.Enable = 'on';
            app.Lamp_15.Color = [0 1 0];
            app.UDLSubmitted = false(1, app.noUDLs);    


            if (value==0)
                app.Lamp_20.Color = [0 1 0];
            else
                app.DistLoadsDropDown.Items = arrayfun(@(i) sprintf('UDL %d', i), 1:value, 'UniformOutput', false);
            end
        end

        % Button pushed function: SubmitResetButton_5
        function SubmitResetButton_5Pushed(app, event)
            %No. OF MOMENTS
            %app.resetdetails(5);
            value = app.NoMomentsSpinner.Value;
            app.noMoments = value;
            app.Lamp_16.Color = [0 1 0];
            app.MomentSubmitted  = false(1, app.noMoments);
            app.NodePositionDropDown.Enable = 'on';%node selection
            app.YPosmEditField.Enable = 'on';
            app.XPosmEditField.Enable = 'on';
            app.FixedCheckBox.Enable = 'on';
            app.SubmitButton.Enable = 'on';

            if (value==0)
                app.Lamp_21.Color = [0 1 0];
            else
                app.MomentsDropDown.Items = arrayfun(@(i) sprintf('Moment %d', i), 1:value, 'UniformOutput', false);
            end

            % Node data
            app.SubmitButton.Enable = 'on';
            app.NodePositionDropDown.Enable = 'on'; % node selection
            app.YPosmEditField.Enable = 'on';
            app.XPosmEditField.Enable = 'on';
            app.FixedCheckBox.Enable = 'on';

            % Element data
            app.SubmitButton_2.Enable = 'on';
            app.ElementDataDropDown.Enable = 'on'; % element selection
            app.DropDown_2.Enable = 'on'; % kpa
            app.Point1DropDown.Enable = 'on'; % first node
            app.Point2DropDown.Enable = 'on'; % second node
            app.Im4EditField.Enable = 'on'; % moment of inertia
            app.Aream2EditField.Enable = 'on'; % element area
            app.EEditField.Enable = 'on'; % Young's modulus

            if(app.noForces~=0)
            % PointForce data
            app.SubmitButton_3.Enable = 'on';
            app.PointForceDataDropDown.Enable = 'on'; % force selection
            app.DropDown_3.Enable = 'on'; % force magnitude
            app.ForceAxisDropDown.Enable = 'on'; % force direction
            app.NodeDropDown_4.Enable = 'on'; % force location
            app.FEditField.Enable = 'on'; % force value
            end
            if(app.noUDLs~=0)
            % Distributed load data
            app.SubmitButton_4.Enable = 'on';
            app.DistLoadsDropDown.Enable = 'on'; % load selection
            app.NodeDropDown_3.Enable = 'on'; % element selection
            app.ForceAxisDropDown_2.Enable = 'on'; % force direction
            app.DropDown_4.Enable = 'on'; % scale
            app.FEditField_3.Enable = 'on'; % distforce value
            end
            if(app.noMoments~=0)
            % Moment data
            app.SubmitButton_5.Enable = 'on';
            app.MomentsDropDown.Enable = 'on'; % moment selection
            app.NodeDropDown.Enable = 'on'; % location
            app.DropDown.Enable = 'on'; % magnitude
            app.DirectionDropDown.Enable = 'on'; % direction
            app.FEditField_2.Enable = 'on'; % moment value
            end
        end

        % Button pushed function: SubmitButton
        function SubmitButtonPushed(app, event)
            %node position data button
            nodeIdx = str2double(regexp(app.NodePositionDropDown.Value, '\d+', 'match', 'once'));
            app.XPosmEditField.Value = app.X(nodeIdx);
            app.YPosmEditField.Value = app.Y(nodeIdx);
            if(app.FixedCheckBox.Value == 1)
                app.NDU= app.NDU+1;
                n = nodeIdx * 3;
                app.dzero = [app.dzero, n, n+1, n+2];
            end
            % Mark this node as submitted (so it won't be counted again)
            if ~app.nodeSubmitted(nodeIdx)
                app.nodeSubmitted(nodeIdx) = true;
                app.nodeSubmitCount = app.nodeSubmitCount + 1; 
            end
                        
            % Check if all nodes have been submitted
            if app.nodeSubmitCount == app.n_nodes
                app.Lamp_17.Color = [0 1 0];
                app.checkReady2Solve();
            end
            disp(string(app.NodePositionDropDown.Value) + " submitted")

            disp(app.nodeSubmitted);
            disp(app.n_nodes);
            disp(app.nodeSubmitCount);
        end

        % Button pushed function: SubmitButton_2
        function SubmitButton_2Pushed(app, event)
            %element data button
            Idx = str2double(regexp(app.ElementDataDropDown.Value, '\d+', 'match', 'once'));
            
            E_local = app.EEditField.Value;
            EScale = app.DropDown_2.Value;
            switch(EScale)
                case 'Pa'
                    Emultiplier = 1;
                case 'KPa'
                    Emultiplier = 1000;
                case 'MPa'
                    Emultiplier = 1000000;
                case 'GPa'
                    Emultiplier = 1000000000;
            end
            app.E(Idx) = E_local*Emultiplier;
            app.ncon1(1, Idx) = str2double(regexp(app.Point1DropDown.Value, '\d+', 'match', 'once'));
            app.ncon2(1, Idx) = str2double(regexp(app.Point2DropDown.Value, '\d+', 'match', 'once'));

            app.I(Idx) = app.Im4EditField.Value;
            app.Area(Idx) = app.Aream2EditField;
            
            % Mark this element as submitted 
            if ~app.elementSubmitted(Idx)
                app.elementSubmitted(Idx) = true;
                app.elementSubmitCount = app.elementSubmitCount + 1;
            end

            % Check if all elements have been submitted
            if app.elementSubmitCount == app.n_element
                app.Lamp_18.Color = [0 1 0];
            end
        end

        % Button pushed function: SubmitButton_3
        function SubmitButton_3Pushed(app, event)
            %force data
            Idx = str2double(regexp(app.PointForceDataDropDown.Value, '\d+', 'match', 'once'));
            forceNode = str2double(regexp(app.NodeDropDown_4.Value, '\d+', 'match', 'once'));
            Flocal = app.FEditField.Value;
            FScale = app.DropDown_3.Value;
            switch(FScale)
                case 'N'
                    FMultiplier = 1;
                case 'kN'
                    FMultiplier = 1000;
            end
            if(app.ForceAxisDropDown.Value == 'X')
                app.Fx(forceNode) = Flocal*FMultiplier;
            else
                app.Fy(forceNode) = Flocal*FMultiplier;
            end
            % Mark this force as submitted
            if ~app.forceSubmitted(Idx)
                app.forceSubmitted(Idx) = true;
                app.forceSubmitCount = app.forceSubmitCount + 1;
            end
            % Check if all forces have been submitted
            if app.forceSubmitCount == app.noForces
                app.Lamp_19.Color = [0 1 0];
                app.checkReady2Solve();
            end
        end

        % Button pushed function: SubmitButton_4
        function SubmitButton_4Pushed(app, event)
            %UDL data
            Idx = str2double(regexp(app.DistLoadsDropDown.Value, '\d+', 'match', 'once'));
            UDL_el = str2double(regexp(app.NodeDropDown_3.Value, '\d+', 'match', 'once'));
            UDLlocal = app.FEditField_3.Value;
            udlScale = app.DropDown_4.Value;
            switch(udlScale)
                case 'N/m'
                    FMultiplier = 1;
                case 'kN/m'
                    FMultiplier = 1000;
            end
            if(app.ForceAxisDropDown.Value == 'X')
                app.qx(UDL_el) = UDLlocal*FMultiplier;
            else
                app.qy(UDL_el) = UDLlocal*FMultiplier;
            end
            % Mark this force as submitted
            if ~app.UDLSubmitted(Idx)
                app.UDLSubmitted(Idx) = true;
                app.UDLSubmitCount = app.UDLSubmitCount + 1;
            end
            % Check if all forces have been submitted
            if app.UDLSubmitCount == app.noForces
                app.Lamp_20.Color = [0 1 0];
                app.checkReady2Solve();
            end            
        end

        % Button pushed function: SubmitButton_5
        function SubmitButton_5Pushed(app, event)
            % Moment data
            %UDL data
            Idx = str2double(regexp(app.MomentsDropDown.Value, '\d+', 'match', 'once'));
            momIdx = str2double(regexp(app.NodeDropDown.Value, '\d+', 'match', 'once'));
            momLocal = app.FEditField_2.Value;
            momScale = app.DropDown.Value;
            switch(momScale)
                case 'Nm'
                    FMultiplier = 1;
                case 'kNm'
                    FMultiplier = 1000;
            end
            if(app.DirectionDropDown.Value == 'Clockwise')
                app.M(momIdx) = -momLocal*FMultiplier;
            else
                app.M(momIdx) = momLocal*FMultiplier;
            end
            % Mark this force as submitted
            if ~app.MomentSubmitted(Idx)
                app.MomentSubmitted(Idx) = true;
                app.MomentSubmitCount = app.MomentSubmitCount + 1;
            end
            % Check if all forces have been submitted
            if app.MomentSubmitCount == app.noForces
                app.Lamp_21.Color = [0 1 0];
                app.checkReady2Solve();
            end            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.ProblemViewerTab = uitab(app.TabGroup);
            app.ProblemViewerTab.Title = 'Problem Viewer';
            app.UIAxes_4 = uiaxes(app.ProblemViewerTab);
            xlabel(app.UIAxes_4, 'X (m)')
            ylabel(app.UIAxes_4, 'Y (m)')
            zlabel(app.UIAxes_4, 'Z')
            app.SolveButton = uibutton(app.ProblemViewerTab, 'push');
            app.SolveButton.Enable = 'off';
            app.SolveButton.Text = 'Solve';
            app.GivenStructureLabel = uilabel(app.ProblemViewerTab);
            app.GivenStructureLabel.Text = 'Given Structure';
            app.AlldatareadyLabel = uilabel(app.ProblemViewerTab);
            app.AlldatareadyLabel.Text = 'All data ready?';
            app.Lamp_11 = uilamp(app.ProblemViewerTab);
            app.DataPanel = uipanel(app.ProblemViewerTab);
            app.DataPanel.Title = 'Data';
            app.SubmitResetButton = uibutton(app.DataPanel, 'push');
            app.SubmitResetButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitResetButtonPushed, true);
            app.SubmitResetButton.Enable = 'off';
            app.SubmitResetButton.Text = 'Submit/Reset';
            app.ProblemSelectionDropDown = uidropdown(app.DataPanel);
            app.ProblemSelectionDropDown.Items = {'Custom', 'Q1', 'Q2', 'Q3', 'Q4'};
            app.ProblemSelectionDropDown.ValueChangedFcn = createCallbackFcn(app, @ProblemSelectionDropDownValueChanged, true);
            app.ProblemSelectionDropDown.Value = 'Custom';
            app.NoNodesSpinner = uispinner(app.DataPanel);
            app.NoNodesSpinner.Limits = [0 Inf];
            app.NoNodesSpinner.Enable = 'off';
            app.NoNodesSpinner.Value = 2;
            app.NoElementsSpinner = uispinner(app.DataPanel);
            app.NoElementsSpinner.Limits = [1 Inf];
            app.NoElementsSpinner.Value = 1;
            app.NoPointForcesSpinner = uispinner(app.DataPanel);
            app.NoPointForcesSpinner.Limits = [0 Inf];
            app.NoPointForcesSpinner.Enable = 'off';
            app.NoDistributedForcesSpinner = uispinner(app.DataPanel);
            app.NoDistributedForcesSpinner.Limits = [0 Inf];
            app.NoDistributedForcesSpinner.Enable = 'off';
            app.NoMomentsSpinner = uispinner(app.DataPanel);
            app.NoMomentsSpinner.Limits = [0 Inf];
            app.NoMomentsSpinner.Enable = 'off';
            app.NodePositionDropDown = uidropdown(app.DataPanel);
            app.NodePositionDropDown.Items = {'Node 1'};
            app.NodePositionDropDown.Enable = 'off';
            app.NodePositionDropDown.Value = 'Node 1';
            app.XPosmEditField = uieditfield(app.DataPanel, 'numeric');
            app.XPosmEditField.Enable = 'off';
            app.YPosmEditField = uieditfield(app.DataPanel, 'numeric');
            app.YPosmEditField.Enable = 'off';
            app.ElementDataDropDown = uidropdown(app.DataPanel);
            app.ElementDataDropDown.Items = {'Element 1'};
            app.ElementDataDropDown.Enable = 'off';
            app.ElementDataDropDown.Value = 'Element 1';
            app.Point1DropDown = uidropdown(app.DataPanel);
            app.Point1DropDown.Items = {};
            app.Point1DropDown.Enable = 'off';
            app.Point1DropDown.Placeholder = 'Node 1';
            app.Point1DropDown.Value = {};
            app.Point2DropDown = uidropdown(app.DataPanel);
            app.Point2DropDown.Items = {};
            app.Point2DropDown.Enable = 'off';
            app.Point2DropDown.Placeholder = 'Node 1';
            app.Point2DropDown.Value = {};
            app.Aream2EditField = uieditfield(app.DataPanel, 'numeric');
            app.Aream2EditField.Limits = [0 Inf];
            app.Aream2EditField.Enable = 'off';
            app.EEditField = uieditfield(app.DataPanel, 'numeric');
            app.EEditField.Limits = [0 Inf];
            app.EEditField.Enable = 'off';
            app.PointForceDataDropDown = uidropdown(app.DataPanel);
            app.PointForceDataDropDown.Items = {'Force 1'};
            app.PointForceDataDropDown.Enable = 'off';
            app.PointForceDataDropDown.Value = 'Force 1';
            app.FEditField = uieditfield(app.DataPanel, 'numeric');
            app.FEditField.Enable = 'off';
            app.Im4EditField = uieditfield(app.DataPanel, 'numeric');
            app.Im4EditField.Limits = [0 Inf];
            app.Im4EditField.Enable = 'off';
            app.ForceAxisDropDown = uidropdown(app.DataPanel);
            app.ForceAxisDropDown.Items = {'X', 'Y'};
            app.ForceAxisDropDown.Enable = 'off';
            app.ForceAxisDropDown.Value = 'X';
            app.Lamp_12 = uilamp(app.DataPanel);
            app.SubmitResetButton_2 = uibutton(app.DataPanel, 'push');
            app.SubmitResetButton_2.ButtonPushedFcn = createCallbackFcn(app, @SubmitResetButton_2Pushed, true);
            app.SubmitResetButton_2.Text = 'Submit/Reset';
            app.Lamp_13 = uilamp(app.DataPanel);
            app.SubmitResetButton_3 = uibutton(app.DataPanel, 'push');
            app.SubmitResetButton_3.ButtonPushedFcn = createCallbackFcn(app, @SubmitResetButton_3Pushed, true);
            app.SubmitResetButton_3.Enable = 'off';
            app.SubmitResetButton_3.Text = 'Submit/Reset';
            app.Lamp_14 = uilamp(app.DataPanel);
            app.SubmitResetButton_4 = uibutton(app.DataPanel, 'push');
            app.SubmitResetButton_4.ButtonPushedFcn = createCallbackFcn(app, @SubmitResetButton_4Pushed, true);
            app.SubmitResetButton_4.Enable = 'off';
            app.SubmitResetButton_4.Text = 'Submit/Reset';
            app.DropDown = uidropdown(app.DataPanel);
            app.DropDown.Items = {'Nm', 'kNm'};
            app.DropDown.Enable = 'off';
            app.DropDown.Value = 'Nm';
            app.Lamp_15 = uilamp(app.DataPanel);
            app.SubmitResetButton_5 = uibutton(app.DataPanel, 'push');
            app.SubmitResetButton_5.ButtonPushedFcn = createCallbackFcn(app, @SubmitResetButton_5Pushed, true);
            app.SubmitResetButton_5.Enable = 'off';
            app.SubmitResetButton_5.Text = 'Submit/Reset';
            app.Lamp_16 = uilamp(app.DataPanel);
            app.DataLoadedLabel = uilabel(app.DataPanel);
            app.DataLoadedLabel.Text = 'Data Loaded';
            app.SubmitButton = uibutton(app.DataPanel, 'push');
            app.SubmitButton.ButtonPushedFcn = createCallbackFcn(app, @SubmitButtonPushed, true);
            app.SubmitButton.Enable = 'off';
            app.SubmitButton.Text = 'Submit';
            app.Lamp_17 = uilamp(app.DataPanel);
            app.FixedCheckBox = uicheckbox(app.DataPanel);
            app.FixedCheckBox.Enable = 'off';
            app.FixedCheckBox.Text = 'Fixed?';
            app.SubmitButton_2 = uibutton(app.DataPanel, 'push');
            app.SubmitButton_2.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_2Pushed, true);
            app.SubmitButton_2.Enable = 'off';
            app.SubmitButton_2.Text = 'Submit';
            app.Lamp_18 = uilamp(app.DataPanel);
            app.DropDown_2 = uidropdown(app.DataPanel);
            app.DropDown_2.Items = {'Pa', 'KPa', 'MPa', 'GPa'};
            app.DropDown_2.Enable = 'off';
            app.DropDown_2.Value = 'KPa';
            app.SubmitButton_3 = uibutton(app.DataPanel, 'push');
            app.SubmitButton_3.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_3Pushed, true);
            app.SubmitButton_3.Enable = 'off';
            app.SubmitButton_3.Text = 'Submit';
            app.Lamp_19 = uilamp(app.DataPanel);
            app.DropDown_3 = uidropdown(app.DataPanel);
            app.DropDown_3.Items = {'N', 'kN'};
            app.DropDown_3.Enable = 'off';
            app.DropDown_3.Value = 'kN';
            app.SubmitButton_4 = uibutton(app.DataPanel, 'push');
            app.SubmitButton_4.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_4Pushed, true);
            app.SubmitButton_4.Enable = 'off';
            app.SubmitButton_4.Text = 'Submit';
            app.Lamp_20 = uilamp(app.DataPanel);
            app.DropDown_4 = uidropdown(app.DataPanel);
            app.DropDown_4.Items = {'N/m', 'kN/m'};
            app.DropDown_4.Enable = 'off';
            app.DropDown_4.Value = 'N/m';
            app.SubmitButton_5 = uibutton(app.DataPanel, 'push');
            app.SubmitButton_5.ButtonPushedFcn = createCallbackFcn(app, @SubmitButton_5Pushed, true);
            app.SubmitButton_5.Enable = 'off';
            app.SubmitButton_5.Text = 'Submit';
            app.Lamp_21 = uilamp(app.DataPanel);
            app.ForceAxisDropDownLabel = uilabel(app.DataPanel);
            app.ForceAxisDropDownLabel.Text = 'Force Axis';
            app.FEditField_3Label = uilabel(app.DataPanel);
            app.FEditField_3Label.Text = 'F';
            app.FEditField_2 = uieditfield(app.DataPanel, 'numeric');
            app.FEditField_2.Enable = 'off';
            app.DirectionDropDownLabel = uilabel(app.DataPanel);
            app.DirectionDropDownLabel.Text = 'Direction';
            app.DirectionDropDown = uidropdown(app.DataPanel);
            app.DirectionDropDown.Items = {'Clockwise', 'Anti-Clockwise'};
            app.DirectionDropDown.Enable = 'off';
            app.DirectionDropDown.Value = 'Clockwise';
            app.NodeDropDown_2Label = uilabel(app.DataPanel);
            app.NodeDropDown_2Label.Text = 'Node';
            app.NodeDropDown = uidropdown(app.DataPanel);
            app.NodeDropDown.Items = {};
            app.NodeDropDown.Enable = 'off';
            app.NodeDropDown.Placeholder = 'Node 1';
            app.NodeDropDown.Value = {};
            app.ProblemSelectionDropDownLabel = uilabel(app.DataPanel);
            app.ProblemSelectionDropDownLabel.Text = 'Problem Selection';
            app.NoNodesSpinnerLabel = uilabel(app.DataPanel);
            app.NoNodesSpinnerLabel.Text = 'No. Nodes';
            app.NoElementsSpinnerLabel = uilabel(app.DataPanel);
            app.NoElementsSpinnerLabel.Text = 'No. Elements';
            app.NoPointForcesSpinnerLabel = uilabel(app.DataPanel);
            app.NoPointForcesSpinnerLabel.Text = 'No. Point Forces';
            app.NoDistributedForcesSpinnerLabel = uilabel(app.DataPanel);
            app.NoDistributedForcesSpinnerLabel.Text = 'No. Distributed Forces';
            app.Im4EditFieldLabel = uilabel(app.DataPanel);
            app.Im4EditFieldLabel.Interpreter = 'tex';
            app.Im4EditFieldLabel.Text = 'I (m^4)';
            app.NoMomentsSpinnerLabel = uilabel(app.DataPanel);
            app.NoMomentsSpinnerLabel.Text = 'No. Moments';
            app.NodePositionDropDownLabel = uilabel(app.DataPanel);
            app.NodePositionDropDownLabel.Text = 'Node Position';
            app.FEditFieldLabel = uilabel(app.DataPanel);
            app.FEditFieldLabel.Text = 'F';
            app.XPosmEditFieldLabel = uilabel(app.DataPanel);
            app.XPosmEditFieldLabel.Text = 'X Pos. (m)';
            app.YPosmEditFieldLabel = uilabel(app.DataPanel);
            app.YPosmEditFieldLabel.Text = 'Y Pos. (m)';
            app.PointForceDataDropDownLabel = uilabel(app.DataPanel);
            app.PointForceDataDropDownLabel.Text = 'Point Force Data';
            app.ElementDataDropDownLabel = uilabel(app.DataPanel);
            app.ElementDataDropDownLabel.Text = 'Element Data';
            app.Point1DropDownLabel = uilabel(app.DataPanel);
            app.Point1DropDownLabel.Text = 'Point 1';
            app.EEditFieldLabel = uilabel(app.DataPanel);
            app.EEditFieldLabel.Text = 'E';
            app.Point2DropDownLabel = uilabel(app.DataPanel);
            app.Point2DropDownLabel.Text = 'Point 2';
            app.Aream2EditFieldLabel = uilabel(app.DataPanel);
            app.Aream2EditFieldLabel.Interpreter = 'tex';
            app.Aream2EditFieldLabel.Text = 'Area (m^2)';
            app.FEditField_3 = uieditfield(app.DataPanel, 'numeric');
            app.FEditField_3.Enable = 'off';
            app.FEditFieldLabel_2 = uilabel(app.DataPanel);
            app.FEditFieldLabel_2.Text = 'F';
            app.MomentsDropDownLabel = uilabel(app.DataPanel);
            app.MomentsDropDownLabel.Text = 'Moments';
            app.MomentsDropDown = uidropdown(app.DataPanel);
            app.MomentsDropDown.Items = {'Moment 1'};
            app.MomentsDropDown.Enable = 'off';
            app.MomentsDropDown.Value = 'Moment 1';
            app.DistLoadsDropDownLabel = uilabel(app.DataPanel);
            app.DistLoadsDropDownLabel.Text = 'Dist. Loads';
            app.DistLoadsDropDown = uidropdown(app.DataPanel);
            app.DistLoadsDropDown.Items = {'Load 1'};
            app.DistLoadsDropDown.Enable = 'off';
            app.DistLoadsDropDown.Tooltip = {'Distributed Loads'};
            app.DistLoadsDropDown.Value = 'Load 1';
            app.NodesLabel = uilabel(app.DataPanel);
            app.NodesLabel.Text = 'Nodes';
            app.ElementsLabel = uilabel(app.DataPanel);
            app.ElementsLabel.Text = 'Elements';
            app.PointForcesLabel = uilabel(app.DataPanel);
            app.PointForcesLabel.Text = 'Point Forces';
            app.DistLoadsLabel = uilabel(app.DataPanel);
            app.DistLoadsLabel.Text = 'Dist. Loads';
            app.MomentsLabel = uilabel(app.DataPanel);
            app.MomentsLabel.Text = 'Moments';
            app.ForceAxisDropDown_2 = uidropdown(app.DataPanel);
            app.ForceAxisDropDown_2.Items = {'X', 'Y'};
            app.ForceAxisDropDown_2.Enable = 'off';
            app.ForceAxisDropDown_2.Value = 'X';
            app.ForceAxisDropDownLabel_2 = uilabel(app.DataPanel);
            app.ForceAxisDropDownLabel_2.Text = 'Force Axis';
            app.NodeDropDown_2Label_2 = uilabel(app.DataPanel);
            app.NodeDropDown_2Label_2.Text = 'Element';
            app.NodeDropDown_3 = uidropdown(app.DataPanel);
            app.NodeDropDown_3.Items = {'Element 1'};
            app.NodeDropDown_3.Enable = 'off';
            app.NodeDropDown_3.Value = 'Element 1';
            app.NodeDropDown_2Label_3 = uilabel(app.DataPanel);
            app.NodeDropDown_2Label_3.Text = 'Node';
            app.NodeDropDown_4 = uidropdown(app.DataPanel);
            app.NodeDropDown_4.Items = {};
            app.NodeDropDown_4.Enable = 'off';
            app.NodeDropDown_4.Placeholder = 'Node 1';
            app.NodeDropDown_4.Value = {};
            app.ResetAllButton = uibutton(app.ProblemViewerTab, 'push');
            app.ResetAllButton.Tooltip = {'Be careful!'};
            app.ResetAllButton.Text = 'Reset All';
            app.SolvedProblemTab = uitab(app.TabGroup);
            app.SolvedProblemTab.Title = 'Solved Problem';
            app.DeformedStructurePanel = uipanel(app.SolvedProblemTab);
            app.DeformedStructurePanel.Title = 'Deformed Structure';
            app.UIAxes_3 = uiaxes(app.DeformedStructurePanel);
            xlabel(app.UIAxes_3, 'X (m)')
            ylabel(app.UIAxes_3, 'Y (m)')
            zlabel(app.UIAxes_3, 'Z')
            app.StartOverButton = uibutton(app.DeformedStructurePanel, 'push');
            app.StartOverButton.Enable = 'off';
            app.StartOverButton.Text = 'Start Over';
            app.UserInputPanel = uipanel(app.SolvedProblemTab);
            app.UserInputPanel.Title = 'User Input';
            app.DeformationScalingLabel = uilabel(app.UserInputPanel);
            app.DeformationScalingLabel.Enable = 'off';
            app.DeformationScalingLabel.Text = {'Deformation'; 'Scaling'};
            app.DeformationScalingSlider = uislider(app.UserInputPanel);
            app.DeformationScalingSlider.Limits = [1 1000];
            app.DeformationScalingSlider.MajorTicks = [1 250 500 750 1000];
            app.DeformationScalingSlider.MinorTicks = [1 50 100 150 200 250 300 350 400 450 500 550 600 650 700 750 800 850 900 950 1000];
            app.DeformationScalingSlider.Enable = 'off';
            app.DeformationScalingSlider.Value = 1;
            app.RotationScalingLabel = uilabel(app.UserInputPanel);
            app.RotationScalingLabel.Enable = 'off';
            app.RotationScalingLabel.Text = {'Rotation'; 'Scaling'};
            app.RotationScalingSlider = uislider(app.UserInputPanel);
            app.RotationScalingSlider.Limits = [1 150];
            app.RotationScalingSlider.MajorTicks = [1 20 40 60 80 100 120 150];
            app.RotationScalingSlider.MinorTicks = [1 5 10 15 20 25 30 35 40 45 50 55 60 65 70 75 80 85 90 95 100 105 110 115 120 125 130 135 140 145 150];
            app.RotationScalingSlider.Enable = 'off';
            app.RotationScalingSlider.Value = 1;
            app.ApplyButton = uibutton(app.UserInputPanel, 'push');
            app.ApplyButton.Enable = 'off';
            app.ApplyButton.Text = 'Apply';
            app.ApplyButton_2 = uibutton(app.UserInputPanel, 'push');
            app.ApplyButton_2.Enable = 'off';
            app.ApplyButton_2.Text = 'Apply';
            app.DataOutputPanel = uipanel(app.SolvedProblemTab);
            app.DataOutputPanel.Title = 'Data Output';
            app.UITable = uitable(app.DataOutputPanel);
            app.UITable.ColumnName = {'Column 1'; 'Column 2'; 'Column 3'; 'Column 4'};
            app.UITable.RowName = {};
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = cae_gui_scratch

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end